classdef WarlockGearApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GearAndDPS                      matlab.ui.container.Tab
        NeckInt                         matlab.ui.control.NumericEditField
        NeckSP                          matlab.ui.control.NumericEditField
        NeckHit                         matlab.ui.control.NumericEditField
        NeckCrit                        matlab.ui.control.NumericEditField
        ShouldersInt                    matlab.ui.control.NumericEditField
        ShouldersSP                     matlab.ui.control.NumericEditField
        ShouldersHit                    matlab.ui.control.NumericEditField
        ShouldersCrit                   matlab.ui.control.NumericEditField
        BackInt                         matlab.ui.control.NumericEditField
        BackSP                          matlab.ui.control.NumericEditField
        BackHit                         matlab.ui.control.NumericEditField
        BackCrit                        matlab.ui.control.NumericEditField
        ChestInt                        matlab.ui.control.NumericEditField
        ChestSP                         matlab.ui.control.NumericEditField
        ChestHit                        matlab.ui.control.NumericEditField
        ChestCrit                       matlab.ui.control.NumericEditField
        WristsInt                       matlab.ui.control.NumericEditField
        WristsSP                        matlab.ui.control.NumericEditField
        WristsHit                       matlab.ui.control.NumericEditField
        WristsCrit                      matlab.ui.control.NumericEditField
        Finger1Int                      matlab.ui.control.NumericEditField
        Finger1SP                       matlab.ui.control.NumericEditField
        Finger1Hit                      matlab.ui.control.NumericEditField
        Finger1Crit                     matlab.ui.control.NumericEditField
        Finger2Int                      matlab.ui.control.NumericEditField
        Finger2SP                       matlab.ui.control.NumericEditField
        Finger2Hit                      matlab.ui.control.NumericEditField
        Finger2Crit                     matlab.ui.control.NumericEditField
        WaistInt                        matlab.ui.control.NumericEditField
        WaistSP                         matlab.ui.control.NumericEditField
        WaistHit                        matlab.ui.control.NumericEditField
        WaistCrit                       matlab.ui.control.NumericEditField
        LegsInt                         matlab.ui.control.NumericEditField
        LegsSP                          matlab.ui.control.NumericEditField
        LegsHit                         matlab.ui.control.NumericEditField
        LegsCrit                        matlab.ui.control.NumericEditField
        FeetInt                         matlab.ui.control.NumericEditField
        FeetSP                          matlab.ui.control.NumericEditField
        FeetHit                         matlab.ui.control.NumericEditField
        FeetCrit                        matlab.ui.control.NumericEditField
        Trinket1Int                     matlab.ui.control.NumericEditField
        Trinket1SP                      matlab.ui.control.NumericEditField
        Trinket1Hit                     matlab.ui.control.NumericEditField
        Trinket1Crit                    matlab.ui.control.NumericEditField
        Trinket2Int                     matlab.ui.control.NumericEditField
        Trinket2SP                      matlab.ui.control.NumericEditField
        Trinket2Hit                     matlab.ui.control.NumericEditField
        Trinket2Crit                    matlab.ui.control.NumericEditField
        WandInt                         matlab.ui.control.NumericEditField
        WandSP                          matlab.ui.control.NumericEditField
        WandHit                         matlab.ui.control.NumericEditField
        WandCrit                        matlab.ui.control.NumericEditField
        WeaponInt                       matlab.ui.control.NumericEditField
        WeaponSP                        matlab.ui.control.NumericEditField
        WeaponHit                       matlab.ui.control.NumericEditField
        WeaponCrit                      matlab.ui.control.NumericEditField
        OffhandInt                      matlab.ui.control.NumericEditField
        OffhandSP                       matlab.ui.control.NumericEditField
        OffhandHit                      matlab.ui.control.NumericEditField
        OffhandCrit                     matlab.ui.control.NumericEditField
        HeadDropDown_2Label             matlab.ui.control.Label
        HeadDropDown                    matlab.ui.control.DropDown
        NeckDropDown_2Label             matlab.ui.control.Label
        NeckDropDown                    matlab.ui.control.DropDown
        ShouldersDropDown_2Label        matlab.ui.control.Label
        ShouldersDropDown               matlab.ui.control.DropDown
        ChestDropDown_2Label            matlab.ui.control.Label
        ChestDropDown                   matlab.ui.control.DropDown
        WristsDropDownLabel             matlab.ui.control.Label
        WristsDropDown                  matlab.ui.control.DropDown
        HandsDropDown_2Label            matlab.ui.control.Label
        HandsDropDown                   matlab.ui.control.DropDown
        WaistDropDown_2Label            matlab.ui.control.Label
        WaistDropDown                   matlab.ui.control.DropDown
        LegsDropDown_2Label             matlab.ui.control.Label
        LegsDropDown                    matlab.ui.control.DropDown
        FeetDropDownLabel               matlab.ui.control.Label
        FeetDropDown                    matlab.ui.control.DropDown
        Finger1DropDownLabel            matlab.ui.control.Label
        Finger1DropDown                 matlab.ui.control.DropDown
        Trinket1DropDown_2Label         matlab.ui.control.Label
        Trinket1DropDown                matlab.ui.control.DropDown
        Trinket2DropDown_2Label         matlab.ui.control.Label
        Trinket2DropDown                matlab.ui.control.DropDown
        WeaponDropDown_2Label           matlab.ui.control.Label
        WeaponDropDown                  matlab.ui.control.DropDown
        WandDropDown_2Label             matlab.ui.control.Label
        WandDropDown                    matlab.ui.control.DropDown
        OffhandDropDown_2Label          matlab.ui.control.Label
        OffhandDropDown                 matlab.ui.control.DropDown
        BackDropDown_2Label             matlab.ui.control.Label
        BackDropDown                    matlab.ui.control.DropDown
        IntEditFieldLabel_4             matlab.ui.control.Label
        HeadInt                         matlab.ui.control.NumericEditField
        SPEditFieldLabel_4              matlab.ui.control.Label
        HeadSP                          matlab.ui.control.NumericEditField
        HitLabel_4                      matlab.ui.control.Label
        HeadHit                         matlab.ui.control.NumericEditField
        CritLabel_4                     matlab.ui.control.Label
        HeadCrit                        matlab.ui.control.NumericEditField
        IntEditFieldLabel_5             matlab.ui.control.Label
        HandsInt                        matlab.ui.control.NumericEditField
        SPEditFieldLabel_5              matlab.ui.control.Label
        HandsSP                         matlab.ui.control.NumericEditField
        HitLabel_5                      matlab.ui.control.Label
        HandsHit                        matlab.ui.control.NumericEditField
        CritLabel_5                     matlab.ui.control.Label
        HandsCrit                       matlab.ui.control.NumericEditField
        DMTCheckBox                     matlab.ui.control.CheckBox
        OnyCheckBox                     matlab.ui.control.CheckBox
        HakkarCheckBox                  matlab.ui.control.CheckBox
        ShadowElixirCheckBox            matlab.ui.control.CheckBox
        GreaterArcaneElixirCheckBox     matlab.ui.control.CheckBox
        FlaskofSupremePowerCheckBox     matlab.ui.control.CheckBox
        BrilliantWizardOilCheckBox      matlab.ui.control.CheckBox
        Rank10ShadowboltCheckBox        matlab.ui.control.CheckBox
        MoonkinAuraCheckBox             matlab.ui.control.CheckBox
        SongflowerCheckBox              matlab.ui.control.CheckBox
        OptimizeButton                  matlab.ui.control.Button
        Finger2DropDownLabel            matlab.ui.control.Label
        Finger2DropDown                 matlab.ui.control.DropDown
        OptimizeGauge                   matlab.ui.control.LinearGauge
        ManaPotionsCheckBox             matlab.ui.control.CheckBox
        BlastedLandsIntCheckBox         matlab.ui.control.CheckBox
        ElixiroftheSagesCheckBox        matlab.ui.control.CheckBox
        DemonicRunesCheckBox            matlab.ui.control.CheckBox
        ArcaneIntellectCheckBox         matlab.ui.control.CheckBox
        AQ5setbonusCheckBox             matlab.ui.control.CheckBox
        ManaTideCheckBox                matlab.ui.control.CheckBox
        ManaSpringCheckBox              matlab.ui.control.CheckBox
        MagebloodCheckBox               matlab.ui.control.CheckBox
        RendCheckBox                    matlab.ui.control.CheckBox
        SelectAllBuffsConsumesCheckBox  matlab.ui.control.CheckBox
        DMFCheckBox                     matlab.ui.control.CheckBox
        ShadowWeavingCheckBox           matlab.ui.control.CheckBox
        EnchantedLabel                  matlab.ui.control.Label
        HeadEnchant                     matlab.ui.control.CheckBox
        ShouldersEnchant                matlab.ui.control.CheckBox
        ChestEnchant                    matlab.ui.control.CheckBox
        WeaponEnchant                   matlab.ui.control.CheckBox
        WristsEnchant                   matlab.ui.control.CheckBox
        HandsEnchant                    matlab.ui.control.CheckBox
        LegsEnchant                     matlab.ui.control.CheckBox
        TubersCheckBox                  matlab.ui.control.CheckBox
        CurseofShadowsCheckBox          matlab.ui.control.CheckBox
        CorruptionCheckBox              matlab.ui.control.CheckBox
        ImmolateCheckBox                matlab.ui.control.CheckBox
        CurseofAgonyCheckBox            matlab.ui.control.CheckBox
        CurseofDoomCheckBox             matlab.ui.control.CheckBox
        ClipDoTsCheckBox                matlab.ui.control.CheckBox
        MarkoftheWildCheckBox           matlab.ui.control.CheckBox
        FestivalSausageCheckBox         matlab.ui.control.CheckBox
        MP5Label                        matlab.ui.control.Label
        ManaRestorationLabel            matlab.ui.control.Label
        IntLabel                        matlab.ui.control.Label
        WorldBuffsLabel                 matlab.ui.control.Label
        ConsumesLabel                   matlab.ui.control.Label
        DamageMultipliersLabel          matlab.ui.control.Label
        DisableOnetimeBuffsCheckBox     matlab.ui.control.CheckBox
        SelectAllEnchantsCheckBox       matlab.ui.control.CheckBox
        FoodLabel                       matlab.ui.control.Label
        Rank7Corruption                 matlab.ui.control.CheckBox
        Rank8Immolate                   matlab.ui.control.CheckBox
        SoulFireCheckBox                matlab.ui.control.CheckBox
        TalentsTab                      matlab.ui.container.Tab
        TalentTreeDropDownLabel         matlab.ui.control.Label
        TalentTreeDropDown              matlab.ui.control.DropDown
        CataclysmLabel                  matlab.ui.control.Label
        CataclysmPoints                 matlab.ui.control.NumericEditField
        CataclysmMax                    matlab.ui.control.NumericEditField
        BaneLabel                       matlab.ui.control.Label
        BanePoints                      matlab.ui.control.NumericEditField
        BaneMax                         matlab.ui.control.NumericEditField
        DevastationLabel                matlab.ui.control.Label
        DevastationPoints               matlab.ui.control.NumericEditField
        DevastationMax                  matlab.ui.control.NumericEditField
        RuinLabel                       matlab.ui.control.Label
        RuinPoints                      matlab.ui.control.NumericEditField
        RuinMax                         matlab.ui.control.NumericEditField
        SacLabel                        matlab.ui.control.Label
        SacPoints                       matlab.ui.control.NumericEditField
        SacMax                          matlab.ui.control.NumericEditField
        ImprovedLifetapLabel            matlab.ui.control.Label
        ImprovedLifetapPoints           matlab.ui.control.NumericEditField
        ImprovedLifetapMax              matlab.ui.control.NumericEditField
        ShadowMasteryLabel              matlab.ui.control.Label
        ShadowMasteryPoints             matlab.ui.control.NumericEditField
        ShadowMasteryMax                matlab.ui.control.NumericEditField
        ImprovedCorruptionLabel         matlab.ui.control.Label
        ImprovedCorruptionPoints        matlab.ui.control.NumericEditField
        ImprovedCorruptionMax           matlab.ui.control.NumericEditField
        OptimizeOverProbabilityTab      matlab.ui.container.Tab
        FullOptimizePlot                matlab.ui.control.UIAxes
        FullOptimizeButton              matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        MeshPointsSpinnerLabel          matlab.ui.control.Label
        MeshPointsSpinner               matlab.ui.control.Spinner
        FullOptimizeGauge               matlab.ui.control.LinearGauge
        ToLabel                         matlab.ui.control.Label
        ToSpinner                       matlab.ui.control.Spinner
        FromLabel                       matlab.ui.control.Label
        FromSpinner                     matlab.ui.control.Spinner
        TotalsLabel                     matlab.ui.control.Label
        CritLabel_6                     matlab.ui.control.Label
        TotalCrit                       matlab.ui.control.NumericEditField
        HitLabel_6                      matlab.ui.control.Label
        TotalHit                        matlab.ui.control.NumericEditField
        SPEditFieldLabel_6              matlab.ui.control.Label
        TotalSP                         matlab.ui.control.NumericEditField
        IntEditFieldLabel_6             matlab.ui.control.Label
        TotalInt                        matlab.ui.control.NumericEditField
        TotalManaLabel_2                matlab.ui.control.Label
        TotalMana                       matlab.ui.control.NumericEditField
        MaxManaLabel                    matlab.ui.control.Label
        NumberofEditFieldLabel_4        matlab.ui.control.Label
        NumberofShadowBoltCasts         matlab.ui.control.NumericEditField
        NumberofShadowBoltCastsEditFieldLabel  matlab.ui.control.Label
        EstimatedDPSEditFieldLabel_2    matlab.ui.control.Label
        EstimatedDPSEditField           matlab.ui.control.NumericEditField
        FightLengthsecondsEditField_2Label  matlab.ui.control.Label
        FightLengthsecondsEditField     matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        SaveDPSCheckBox                 matlab.ui.control.CheckBox
        SavedDPSEditField               matlab.ui.control.NumericEditField
        SavedDifference                 matlab.ui.control.NumericEditField
        SavedRelativeDifference         matlab.ui.control.EditField
        ExtremeLabel_3                  matlab.ui.control.Label
        PreferenceSlider                matlab.ui.control.Slider
        ReliableLabel                   matlab.ui.control.Label
        AverageLabel                    matlab.ui.control.Label
        NumberofLifeTapCasts            matlab.ui.control.NumericEditField
        NumberofShadowBoltCastsEditFieldLabel_2  matlab.ui.control.Label
        NumberofEditFieldLabel          matlab.ui.control.Label
        MaxMana                         matlab.ui.control.NumericEditField
        DPSPlot                         matlab.ui.control.UIAxes
        RDPSPCheckBox                   matlab.ui.control.CheckBox
        DPSCheckBox                     matlab.ui.control.CheckBox
        NumberofDPSinRaidEditFieldLabel  matlab.ui.control.Label
        NumberofDPSinRaidEditField      matlab.ui.control.NumericEditField
        UsewiggleroomCheckBox           matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        Slot = string(zeros(34,1));
        Name = string(zeros(34,1));
        Int = zeros(34,1);
        SP = zeros(34,1);
        Hit = zeros(34,1);
        Crit = zeros(34,1);
        
        AQSetBonusIDs = 0;
        
        BaseLifeTap = 424;
        
        % These will be updated within the DPS functions if the Rank 10
        % option is selected; rank 9 is default
        BaseSBoltCastTime = 3; SBoltCastTime = 3;
        BaseSBoltCost = 370; SBoltCost = 370;
        BaseSBoltDmgLB = 455;
        BaseSBoltDmgUB = 507;
        BaseSBoltDmg = (455+507)/2;
        SBoltSPCoef = 3/3.5;
        
        BaseMana = 1093;
        BaseCrit = 1.68;
        BaseInt = 108;
        
        IntToCritFactor = 60.6;
        IntToManaFactor = 15;

        BaseCorrCastTime = 2; CorrCastTime = 2;
        BaseCorrDamage = 666; % Rank 6 is default
        CorrDuration = 18; CorrTicks = 6;
        CorrMana = 290;
        CorrSPCoef = 1;
        
        BaseImmoCastTime = 1.5; ImmoCastTime = 1.5;
        BaseImmoDirectDamage = 254;
        BaseImmoDoTDamage = 485; ImmoDoTDuration = 15; % Rank 7 is default
        ImmoDoTTicks = 5;
        ImmoMana = 370;
        ImmoDirectDamageSPCoef = (2/3.5)^2 / (2/3.5 + 1);
        ImmoDoTSPCoef = 1 / (2/3.5 + 1);
        
        CoACastTime = 1.5;
        BaseCoADamage = 1044;
        CoADuration = 24; CoATicks = 12;
        CoAMana = 215;
        CoASPCoef = 1;
        
        BaseCoDCastTime = 1.5; CoDCastTime = 1.5;
        BaseCoDDamage = 3200;
        CoDDuration = 60;
        CoDMana = 300;
        CoDSPCoef = 1;
        
        BaseSFCastTime = 6; SFCastTime = 6; SFCooldown = 60;
        BaseSFCost = 335; SFCost = 335;
        BaseSFDmgLB = 715;
        BaseSFDmgUB = 894;
        BaseSFDmg = (715+894)/2;
        SFSPCoef = 1;
        
        nRaiders = -1; 
        cMesh = linspace(0.05,0.95,1e2);
    end
    
    methods (Access = private)
        
        function UpdateTotals(app)
            GearInt = app.HeadInt.Value + app.NeckInt.Value + app.ShouldersInt.Value + app.BackInt.Value + app.ChestInt.Value + ...
                                 app.WristsInt.Value + app.HandsInt.Value + app.WaistInt.Value + app.LegsInt.Value + app.FeetInt.Value + ...
                                 app.Finger1Int.Value + app.Finger2Int.Value + app.Trinket1Int.Value + app.Trinket2Int.Value + app.WeaponInt.Value + ...
                                 app.OffhandInt.Value + app.WandInt.Value + app.BaseInt;
                             
            GearSP = app.HeadSP.Value + app.NeckSP.Value + app.ShouldersSP.Value + app.BackSP.Value + app.ChestSP.Value + ...
                                app.WristsSP.Value + app.HandsSP.Value + app.WaistSP.Value + app.LegsSP.Value + app.FeetSP.Value + ...
                                app.Finger1SP.Value + app.Finger2SP.Value + app.Trinket1SP.Value + app.Trinket2SP.Value + app.WeaponSP.Value + ...
                                app.OffhandSP.Value + app.WandSP.Value;
            
            GearHit = app.HeadHit.Value + app.NeckHit.Value + app.ShouldersHit.Value + app.BackHit.Value + app.ChestHit.Value + ...
                                 app.WristsHit.Value + app.HandsHit.Value + app.WaistHit.Value + app.LegsHit.Value + app.FeetHit.Value + ...
                                 app.Finger1Hit.Value + app.Finger2Hit.Value + app.Trinket1Hit.Value + app.Trinket2Hit.Value + app.WeaponHit.Value + ...
                                 app.OffhandHit.Value + app.WandHit.Value;
                             
            GearCrit = app.HeadCrit.Value + app.NeckCrit.Value + app.ShouldersCrit.Value + app.BackCrit.Value + app.ChestCrit.Value + ...
                                  app.WristsCrit.Value + app.HandsCrit.Value + app.WaistCrit.Value + app.LegsCrit.Value + app.FeetCrit.Value + ...
                                  app.Finger1Crit.Value + app.Finger2Crit.Value + app.Trinket1Crit.Value + app.Trinket2Crit.Value + app.WeaponCrit.Value + ...
                                  app.OffhandCrit.Value + app.WandCrit.Value + app.BaseCrit;
                              
            AQSetBonus = isequal([find(strcmp(app.Name,app.HeadDropDown.Value)) ...
                             find(strcmp(app.Name,app.ShouldersDropDown.Value)) ...
                             find(strcmp(app.Name,app.ChestDropDown.Value)) ...
                             find(strcmp(app.Name,app.LegsDropDown.Value)) ...
                             find(strcmp(app.Name,app.FeetDropDown.Value))], ...
                                                        app.AQSetBonusIDs);
                                                    
            app.AQ5setbonusCheckBox.Value = AQSetBonus;
                              
            [NewInt,NewSP,NewHit,NewCrit,NewMana,NewMaxMana] = GetTotals(app, ...
                              GearInt,GearSP,GearHit,GearCrit, AQSetBonus);
              
            app.TotalInt.Value = NewInt;
            app.TotalSP.Value = NewSP;
            app.TotalHit.Value = NewHit;
            app.TotalCrit.Value = NewCrit;
            app.TotalMana.Value = NewMana;
            app.MaxMana.Value = NewMaxMana;
        end
        
        
        function UpdateDPS(app)
            
            if (app.UsewiggleroomCheckBox.Value)
                DPSOverWiggle = zeros(5,1);
                NOverWiggle = zeros(5,1);
                NumberOfLifeTapsDPSOverWiggle = zeros(5,1);
                meshOverWiggle = zeros(5,numel(app.cMesh));
                
                for i=1:numel(DPSOverWiggle)
                    [DPSOverWiggle(i),NOverWiggle(i), ...
                     NumberOfLifeTapsDPSOverWiggle(i), meshOverWiggle(i,:)] = ...
                     CalculateDPS(app, ...
                                  app.TotalSP.Value,app.TotalHit.Value, ...
                                  app.TotalCrit.Value,app.TotalMana.Value, ...
                                  app.FightLengthsecondsEditField.Value+2.5*(i-3));
                end
                NewDPS = mean(DPSOverWiggle);
                N = round(mean(NOverWiggle));
                NumberOfLifeTaps = round(mean(NumberOfLifeTapsDPSOverWiggle));
                mesh = mean(meshOverWiggle,1);
            else
                [NewDPS, N, NumberOfLifeTaps, mesh] = CalculateDPS(app, ...
                      app.TotalSP.Value,app.TotalHit.Value, ...
                      app.TotalCrit.Value,app.TotalMana.Value, ...
                      app.FightLengthsecondsEditField.Value);
            end
            
            plot(app.DPSPlot, app.cMesh, mesh)
            hold(app.DPSPlot, "on")
            plot(app.DPSPlot, ...
                [app.PreferenceSlider.Value app.PreferenceSlider.Value], ...
                [min(mesh) max(mesh)], 'k', 'linewidth',1.5)
            plot(app.DPSPlot, app.PreferenceSlider.Value, NewDPS, 'kx', 'markersize',16, 'linewidth',1.5)
            hold(app.DPSPlot, "off")
            app.DPSPlot.XLim = [0 1];
            app.DPSPlot.YLim = [min(mesh) max(mesh)];
                              
            app.EstimatedDPSEditField.Value = NewDPS;
            app.NumberofShadowBoltCasts.Value = N;
            app.NumberofLifeTapCasts.Value = NumberOfLifeTaps;
            
            if (app.SaveDPSCheckBox.Value)
                app.SavedDifference.Value = app.EstimatedDPSEditField.Value - app.SavedDPSEditField.Value;
                app.SavedRelativeDifference.Value = string(round(app.SavedDifference.Value/app.SavedDPSEditField.Value,2)*100) + "%";
                
                if (app.SavedDifference.Value == 0)
                    app.SavedDifference.FontColor = [0 0 0];
                    app.SavedRelativeDifference.FontColor = [0 0 0];
                end
                
                if (app.SavedDifference.Value > 0)
                    app.SavedDifference.FontColor = [0.47,0.67,0.19];
                    app.SavedRelativeDifference.FontColor = [0.47,0.67,0.19];
                end
                
                if (app.SavedDifference.Value < 0)
                    app.SavedDifference.FontColor = [0.64,0.08,0.18];
                    app.SavedRelativeDifference.FontColor = [0.64,0.08,0.18];
                end
            end
            drawnow
        end
        
        
        function [DPS,N,NumberOfLifeTaps,mesh] = CalculateDPS(app,TotalSP, ...
                                TotalHit,TotalCrit,TotalMana,LengthOfFight)
            s = TotalSP;
            p = min(0.83 + TotalHit/100,0.99);
            q = TotalCrit/100;
            
            c = app.PreferenceSlider.Value;
            
            switch c
                case 0
                    c = 0.05;
                case 1
                    c = 0.95;
            end
            
            TotalDoTCastTime = 0;
            TotalDoTMana = 0;
            TotalDoTDamage = 0;
            
            if (app.CorruptionCheckBox.Value)
                nCorrCasts = floor(LengthOfFight/app.CorrDuration);
                TotalDoTCastTime = TotalDoTCastTime + ...
                                             nCorrCasts * app.CorrCastTime;
                TotalDoTMana = TotalDoTMana + nCorrCasts * app.CorrMana;
            if (app.ClipDoTsCheckBox.Value)
                nCorrTicks = app.CorrTicks * nCorrCasts;
            else
                nCorrTicks = app.CorrTicks * nCorrCasts + ...
                    floor(mod(LengthOfFight,app.CorrDuration)/3);
            end
                
                CorrDamage = app.BaseCorrDamage + app.CorrSPCoef * s;
                CorrDamagePerTick = CorrDamage / app.CorrTicks;
                TotalDoTDamage = TotalDoTDamage + ...
                                            nCorrTicks * CorrDamagePerTick;
            end
            
            if (app.ImmolateCheckBox.Value)
                nImmoCasts = floor(LengthOfFight/app.ImmoDoTDuration);
                TotalDoTCastTime = TotalDoTCastTime + ...
                                             nImmoCasts * app.ImmoCastTime;
                TotalDoTMana = TotalDoTMana + nImmoCasts * app.ImmoMana;
            if (app.ClipDoTsCheckBox.Value)
                nImmoTicks = app.CorrTicks * nImmoCasts;
            else
                nImmoTicks = app.CorrTicks * nImmoCasts + ...
                    floor(mod(LengthOfFight,app.ImmoDoTDuration)/3);
            end
                
                ImmoDamage = app.BaseImmoDoTDamage + app.ImmoDoTSPCoef * s;
                ImmoDamagePerTick = ImmoDamage / app.ImmoDoTTicks;
                TotalDoTDamage = TotalDoTDamage + ...
                                            nImmoTicks * ImmoDamagePerTick ...
                  + nImmoCasts * (app.BaseImmoDirectDamage + app.ImmoDirectDamageSPCoef * s);
              % For now, naively add direct damage to DoT amount
            end
            
            if (app.CurseofAgonyCheckBox.Value)
                nCoACasts = floor(LengthOfFight/app.CoADuration);
                TotalDoTCastTime = TotalDoTCastTime + ...
                                               nCoACasts * app.CoACastTime;
                TotalDoTMana = TotalDoTMana + nCoACasts * app.CoAMana;
%             if (app.ClipDoTsCheckBox.Value)
                CoADamage = app.BaseCoADamage + app.CoASPCoef * s;
                TotalDoTDamage = TotalDoTDamage + nCoACasts * CoADamage;
%             else
%                 nCorrTicks = 12 * nCorrCasts + floor(mod(T,18)/3);
%             end
            end
            
            if (app.CurseofDoomCheckBox.Value)
                nCoDCasts = floor(LengthOfFight/app.CoDDuration);
                TotalDoTCastTime = TotalDoTCastTime + ...
                                               nCoDCasts * app.CoDCastTime;
                TotalDoTMana = TotalDoTMana + nCoDCasts * app.CoDMana;
                
                CoDDamage = app.BaseCoDDamage + app.CoDSPCoef * s;
                TotalDoTDamage = TotalDoTDamage + nCoDCasts * CoDDamage;
            end
            
            TotalSFCastTime = 0;
            TotalSFMana = 0;
            TotalSFDamage = 0;
            if (app.SoulFireCheckBox.Value)
                nSFCasts = floor(LengthOfFight/app.SFCooldown);
                TotalSFCastTime = nSFCasts * app.SFCastTime;
                TotalSFMana = nSFCasts * app.SFCost;
                SFDamage = app.BaseSFDmg + app.SFSPCoef * s;
                TotalSFDamage = nSFCasts * SFDamage;
            end
            
            T = LengthOfFight - TotalDoTCastTime - TotalSFCastTime;
            LifeTapAmount = (app.BaseLifeTap + 1.5/3.5 * s)*(1+0.1*app.ImprovedLifetapPoints.Value);
            Mseq = TotalMana - TotalDoTMana - TotalSFMana;
            Nseq = min([floor(Mseq/app.SBoltCost) floor(T/app.SBoltCastTime)]);
            eps = T - Nseq*app.SBoltCastTime; j = 2;
            NumberOfLifeTaps = 0;
            while (eps >= app.SBoltCastTime)
                Mseq(j) = Mseq(j-1)/app.SBoltCost - floor(Mseq(j-1)/app.SBoltCost) + LifeTapAmount;
                Nseq(j) = floor(Mseq(j)/app.SBoltCost);
                eps = eps - 1.5; NumberOfLifeTaps = NumberOfLifeTaps + 1;
                k = Nseq(j);
                while (k > 0 && eps >= app.SBoltCastTime)
                    eps = eps - app.SBoltCastTime;
                    k = k - 1;
                end
                j = j + 1;
            end
            N = sum(Nseq);
            
            mu = (app.BaseSBoltDmg + 3/3.5 * s) * p * ((1.5 + 0.5*app.RuinPoints.Value) * q + 1);
            sigma = sqrt( ((app.BaseSBoltDmgUB-app.BaseSBoltDmgLB)^2/12 + (app.BaseSBoltDmg + 3/3.5 * s)^2) * p * ((1.5 + 0.5*app.RuinPoints.Value)^2 * q * (1 - q) + ((1.5 + 0.5*app.RuinPoints.Value) * q + 1)^2) - (app.BaseSBoltDmg + 3/3.5 * s)^2 * p^2 * ((1.5 + 0.5*app.RuinPoints.Value) * q + 1)^2 );
            
            if (app.DPSCheckBox.Value)
                mesh = (sqrt(2)*erfinv(2*(1-app.cMesh)-1)*sqrt(N)*sigma + N*mu + TotalDoTDamage + TotalSFDamage) / LengthOfFight;
                mesh = mesh * (1 + 0.15*app.SacPoints.Value + 0.1*app.DMFCheckBox.Value) * ...
                            (1 + 0.15*app.ShadowWeavingCheckBox.Value + 0.1*app.CurseofShadowsCheckBox.Value);
                
                DPS = (sqrt(2)*erfinv(2*(1-c)-1)*sqrt(N)*sigma + N*mu + TotalDoTDamage + TotalSFDamage) / LengthOfFight;
                DPS = DPS * (1 + 0.15*app.SacPoints.Value + 0.1*app.DMFCheckBox.Value) * ...
                            (1 + 0.15*app.ShadowWeavingCheckBox.Value + 0.1*app.CurseofShadowsCheckBox.Value);
            else
                mesh = (sqrt(2)*erfinv(2*(1-app.cMesh)-1)*sqrt(N)*sigma/sqrt(app.nRaiders) + N*mu + TotalDoTDamage + TotalSFDamage) / LengthOfFight;
                mesh = mesh * (1 + 0.15*app.SacPoints.Value + 0.1*app.DMFCheckBox.Value) * ...
                            (1 + 0.15*app.ShadowWeavingCheckBox.Value + 0.1*app.CurseofShadowsCheckBox.Value);
                
                DPS = (sqrt(2)*erfinv(2*(1-c)-1)*sqrt(N)*sigma/sqrt(app.nRaiders) + N*mu + TotalDoTDamage + TotalSFDamage) / LengthOfFight;
                DPS = DPS * (1 + 0.15*app.SacPoints.Value + 0.1*app.DMFCheckBox.Value) * ...
                            (1 + 0.15*app.ShadowWeavingCheckBox.Value + 0.1*app.CurseofShadowsCheckBox.Value);
            end
        end
        
        
        function [NewInt,NewSP,NewHit,NewCrit,NewMana,MaxMana] = GetTotals(app, ...
                                    OldInt,OldSP,OldHit,OldCrit,AQSetBonus)
              
            NewInt = OldInt;
            NewSP = OldSP;
            NewHit = OldHit;
            NewCrit = OldCrit;
              
            if (app.DMTCheckBox.Value)
                NewCrit = NewCrit + 3;
            end
                              
            if (app.OnyCheckBox.Value)
                NewCrit = NewCrit + 10;
            end
            
            if (app.MoonkinAuraCheckBox.Value)
                NewCrit = NewCrit + 3;
            end
            
            if (app.SongflowerCheckBox.Value)
                NewCrit = NewCrit + 5;
            end
            
            if (app.ShadowElixirCheckBox.Value)
                NewSP = NewSP + 40;
            end
                              
            if (app.GreaterArcaneElixirCheckBox.Value)
                NewSP = NewSP + 35;
            end
                              
            if (app.FlaskofSupremePowerCheckBox.Value)
                NewSP = NewSP + 150;
            end
            
            if (app.BrilliantWizardOilCheckBox.Value)
                NewSP = NewSP + 36;
                NewCrit = NewCrit + 1;
            end
            
            if (app.BlastedLandsIntCheckBox.Value)
                NewInt = NewInt + 25;
            end
            
            if (app.ElixiroftheSagesCheckBox.Value)
                NewInt = NewInt + 18;
            end
            
            if (app.ArcaneIntellectCheckBox.Value)
                NewInt = NewInt + 31;
            end
            
            if (app.MarkoftheWildCheckBox.Value)
                NewInt = NewInt + 12; % double check this
            end
            
            if (app.FestivalSausageCheckBox.Value)
                NewSP = NewSP + 23;
            end
            
            if (app.TubersCheckBox.Value)
                NewInt = NewInt + 10;
            end
            
            if (app.HeadEnchant.Value)
                NewSP = NewSP + 18;
            end
            
            if (app.ShouldersEnchant.Value)
                NewSP = NewSP + 18;
            end
            
            if (app.LegsEnchant.Value)
                NewSP = NewSP + 18;
            end
            
            if (app.ChestEnchant.Value)
                NewInt = NewInt + 4;
            end
            
            if (app.WeaponEnchant.Value)
                NewSP = NewSP + 30;
            end
            
            if (app.HandsEnchant.Value)
                NewSP = NewSP + 20;
            end
            
            if (app.WristsEnchant.Value)
                NewInt = NewInt + 7;
            end
                        
            if (app.HakkarCheckBox.Value)
                NewInt = NewInt * 1.15;
            end
                              
            NewCrit = NewCrit + NewInt/app.IntToCritFactor;
            OldMana = NewInt*app.IntToManaFactor + app.BaseMana;
            MaxMana = OldMana;
            NewMana = OldMana;
            
            % Direct mana-based consumes are after the initial conversion
            %
            % Technically these consumes have a variable return value,
            % so these calculations will only incorporate the average...
            %
            % Also, they are directly added to total mana pool since
            % mathematically they functionally contribute to extend the
            % stage 1 iterative calculation for N
            %
            % Buffer room of 15 seconds is added manually to account for
            % the extra needed time to actually cast the additional shadow
            % bolts...
            
            if (app.ManaPotionsCheckBox.Value)
                T = app.FightLengthsecondsEditField.Value;
                nBaseUse = floor(T/120); residual = mod(T,120);
                
                % These conditional can probably be simplified analytically
                if (T > 15)
                    if (nBaseUse == 0 && residual >= 15)
                        NewMana = NewMana + (2250+1350)/2;
                    end
                    
                    if (nBaseUse > 0)
                       if (residual >= 30) % Full benefit
                           NewMana = NewMana + (2250+1350)/2*nBaseUse;
                       elseif (residual >= 15) % Partial benefit
                           NewMana = NewMana + ...
                               (2250+1350)/2*(nBaseUse-1+(residual-15)/15);
                       else % only the benefit of past ones
                           NewMana = NewMana + (2250+1350)/2*(nBaseUse-1);
                       end
                    end
                end
            end
            
            if (app.DemonicRunesCheckBox.Value)
                T = app.FightLengthsecondsEditField.Value;
                nBaseUse = floor(T/120); residual = mod(T,120);
                
                % These conditional can probably be simplified analytically
                if (T > 15)
                    if (nBaseUse == 0 && residual >= 15)
                        NewMana = NewMana + (1500+900)/2;
                    end
                    
                    if (nBaseUse > 0)
                       if (residual >= 30) % Full benefit
                           NewMana = NewMana + (1500+900)/2*floor(T/120);
                       elseif(residual >= 15) % Used potion, but full benefit cannot be gained
                           NewMana = NewMana + ...
                            (1500+900)/2*(floor(T/120)-1+(residual-15)/15);
                       end
                    end
                end
            end
            
            if (app.ManaTideCheckBox.Value)
                NewMana = NewMana + ...
                 290*4*floor(app.FightLengthsecondsEditField.Value/(60*5));
            end
            
            if (app.ManaSpringCheckBox.Value)
                NewMana = NewMana + ...
                    27*1.25*floor(app.FightLengthsecondsEditField.Value/2); % 1.25 from assumed resto talents
            end
            
            if (app.MagebloodCheckBox.Value)
                NewMana = NewMana + ...
                         12*floor(app.FightLengthsecondsEditField.Value/5);
            end
            
            if (app.RendCheckBox.Value)
                NewMana = NewMana + ...
                         10*floor(app.FightLengthsecondsEditField.Value/5);
            end
            
            % Update AQ set condition
            if (AQSetBonus)
                app.SBoltCost = app.BaseSBoltCost*(1 - 0.15 - 0.01*app.CataclysmPoints.Value);
            else
                app.SBoltCost = app.BaseSBoltCost*(1 - 0.01*app.CataclysmPoints.Value);
            end
            
            % Update talent effects
            app.SBoltCastTime = app.BaseSBoltCastTime - ...
                                                  0.1*app.BanePoints.Value;
            app.SFCastTime = app.BaseSFCastTime - ...
                                                  0.4*app.BanePoints.Value;
            app.CorrCastTime = app.BaseCorrCastTime - ...
                                    0.1*app.ImprovedCorruptionPoints.Value;
            NewCrit = NewCrit + app.DevastationPoints.Value;
            
            app.nRaiders = app.NumberofDPSinRaidEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Auto-resize
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.15;
            bottom = screenHeight*0.15;
            width = screenWidth*0.7;
            height = screenHeight*0.4;
            drawnow;
            app.UIFigure.Position = [left bottom width height]; 
            
            app.Slot(1) = "Head";
            app.Slot(2) = "Head";
            app.Slot(3) = "Head";
            app.Slot(4) = "Neck";
            app.Slot(5) = "Neck";
            app.Slot(6) = "Neck";
            app.Slot(7) = "Shoulders";
            app.Slot(8) = "Shoulders";
            app.Slot(9) = "Back";
            app.Slot(10) = "Back";
            app.Slot(11) = "Chest";
            app.Slot(12) = "Chest";
            app.Slot(13) = "Chest";
            app.Slot(14) = "Wrists";
            app.Slot(15) = "Wrists";
            app.Slot(16) = "Hands";
            app.Slot(17) = "Hands";
            app.Slot(18) = "Hands";
            app.Slot(19) = "Waist";
            app.Slot(20) = "Waist";
            app.Slot(21) = "Legs";
            app.Slot(22) = "Feet";
            app.Slot(23) = "Feet";
            app.Slot(24) = "Finger";
            app.Slot(25) = "Finger";
            app.Slot(26) = "Finger";
            app.Slot(27) = "Finger";
            app.Slot(28) = "Finger";
            app.Slot(29) = "Finger";
            app.Slot(30) = "Trinket";
            app.Slot(31) = "Trinket";
            app.Slot(32) = "Weapon";
            app.Slot(33) = "Weapon";
            app.Slot(34) = "Wand";
            app.Slot(35) = "Legs";
            app.Slot(36) = "Feet";
            app.Slot(37) = "Wrists";
            app.Slot(38) = "Finger";
            
            app.Name(1) = "Doomcaller's Circlet";
            app.Name(2) = "Mish'undare";
            app.Name(3) = "Hexer's Cover";
            app.Name(4) = "Soul Corrupter's Necklace";
            app.Name(5) = "Amulet of Vek'nilash";
            app.Name(6) = "Choker of the Fire Lord";
            app.Name(7) = "Doomcaller's Mantle";
            app.Name(8) = "Mantle of the Blackwing Cabal";
            app.Name(9) = "Cloak of the Devoured";
            app.Name(10) = "Cloak of the Broodlord";
            app.Name(11) = "Robe of Volatile Power";
            app.Name(12) = "Garb of Royal Ascension";
            app.Name(13) = "Doomcaller's Robes";
            app.Name(14) = "Burrower Bracers";
            app.Name(15) = "Bracers of Arcane Accuracy";
            app.Name(16) = "Dark Storm Gauntlets";
            app.Name(17) = "Nemesis Gloves";
            app.Name(18) = "Ebony Flame Gloves";
            app.Name(19) = "Eyestalk Waistcord";
            app.Name(20) = "Angelista's Grasp";
            app.Name(21) = "Leggings of the Black Blizzard";
            app.Name(22) = "Boots of Epiphany";
            app.Name(23) = "Bloodvine Feet";
            app.Name(24) = "Charm of the Shifting Sands";
            app.Name(25) = "Ritssyn's Ring of Chaos";
            app.Name(26) = "Ring of Unspoken Names";
            app.Name(27) = "Band of Forced Concentration";
            app.Name(28) = "Band of Dark Dominion";
            app.Name(29) = "Ring of Spell Power";
            app.Name(30) = "Talisman of Ephemeral Power";
            app.Name(31) = "Neltharion's Tear";
            app.Name(32) = "Staff of the Shadowflame";
            app.Name(33) = "Blessed Qiraji Acolyte Staff";
            app.Name(34) = "Wand of Qiraji Nobility";
            app.Name(35) = "Doomcaller's Trousers";
            app.Name(36) = "Doomcaller's Footwraps";
            app.Name(37) = "Rockfury Bracers";
            app.Name(38) = "Ring of the Fallen God";
            
            app.AQSetBonusIDs = [1 7 13 35 36];
            
            app.Int(1) = 26; app.SP(1) = 33; app.Hit(1) = 1; app.Crit(1) = 1;
            app.Int(2) = 24; app.SP(2) = 35; app.Hit(2) = 0; app.Crit(2) = 2;
            app.Int(3) = 10; app.SP(3) = 41; app.Hit(3) = 0; app.Crit(3) = 0;
            app.Int(4) = 16; app.SP(4) = 0; app.Hit(4) = 1; app.Crit(4) = 0;
            app.Int(5) = 5; app.SP(5) = 27; app.Hit(5) = 0; app.Crit(5) = 1;
            app.Int(6) = 7; app.SP(6) = 34; app.Hit(6) = 0; app.Crit(6) = 0;
            app.Int(7) = 11; app.SP(7) = 28; app.Hit(7) = 1; app.Crit(7) = 0;
            app.Int(8) = 16; app.SP(8) = 34; app.Hit(8) = 0; app.Crit(8) = 0;
            app.Int(9) = 10; app.SP(9) = 30; app.Hit(9) = 1; app.Crit(9) = 0;
            app.Int(10) = 14; app.SP(10) = 28; app.Hit(10) = 0; app.Crit(10) = 0;
            app.Int(11) = 15; app.SP(11) = 23; app.Hit(11) = 0; app.Crit(11) = 2;
            app.Int(12) = 0; app.SP(12) = 30; app.Hit(12) = 2; app.Crit(12) = 0;
            app.Int(13) = 17; app.SP(13) = 41; app.Hit(13) = 0; app.Crit(13) = 1;
            app.Int(14) = 13; app.SP(14) = 28; app.Hit(14) = 0; app.Crit(14) = 0;
            app.Int(15) = 12; app.SP(15) = 21; app.Hit(15) = 1; app.Crit(15) = 0;
            app.Int(16) = 15; app.SP(16) = 37; app.Hit(16) = 1; app.Crit(16) = 0;
            app.Int(17) = 15; app.SP(17) = 15; app.Hit(17) = 0; app.Crit(17) = 1;
            app.Int(18) = 12; app.SP(18) = 43; app.Hit(18) = 0; app.Crit(18) = 0;
            app.Int(19) = 9; app.SP(19) = 41; app.Hit(19) = 0; app.Crit(19) = 1;
            app.Int(20) = 20; app.SP(20) = 0; app.Hit(20) = 2; app.Crit(20) = 0;
            app.Int(21) = 16; app.SP(21) = 41; app.Hit(21) = 0; app.Crit(21) = 1;
            app.Int(22) = 19; app.SP(22) = 34; app.Hit(22) = 0; app.Crit(22) = 0;
            app.Int(23) = 16; app.SP(23) = 19; app.Hit(23) = 1; app.Crit(23) = 0;
            app.Int(24) = 12; app.SP(24) = 25; app.Hit(24) = 0; app.Crit(24) = 0;
            app.Int(25) = 0; app.SP(25) = 25; app.Hit(25) = 0; app.Crit(25) = 1;
            app.Int(26) = 0; app.SP(26) = 14; app.Hit(26) = 1; app.Crit(26) = 1;
            app.Int(27) = 12; app.SP(27) = 21; app.Hit(27) = 1; app.Crit(27) = 0;
            app.Int(28) = 6; app.SP(28) = 33; app.Hit(28) = 0; app.Crit(28) = 0;
            app.Int(29) = 0; app.SP(29) = 33; app.Hit(29) = 0; app.Crit(29) = 0;
            app.Int(30) = 0; app.SP(30) = 176*6/90; app.Hit(30) = 0; app.Crit(30) = 0;
            app.Int(31) = 0; app.SP(31) = 44; app.Hit(31) = 2; app.Crit(31) = 0;
            app.Int(32) = 29; app.SP(32) = 84; app.Hit(32) = 0; app.Crit(32) = 2;
            app.Int(33) = 33; app.SP(33) = 76; app.Hit(33) = 2; app.Crit(33) = 1;
            app.Int(34) = 0; app.SP(34) = 19; app.Hit(34) = 0; app.Crit(34) = 0;
            app.Int(35) = 24; app.SP(35) = 34; app.Hit(35) = 0; app.Crit(35) = 1;
            app.Int(36) = 16; app.SP(36) = 28; app.Hit(36) = 0; app.Crit(36) = 0;
            app.Int(37) = 0; app.SP(37) = 27; app.Hit(37) = 1; app.Crit(37) = 0;
            app.Int(38) = 6; app.SP(38) = 37; app.Hit(38) = 1; app.Crit(38) = 0;
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            IDs.Head = find(strcmp(app.Slot,"Head")); Nums.Head = numel(IDs.Head);
            IDs.Neck = find(strcmp(app.Slot,"Neck")); Nums.Neck = numel(IDs.Neck);
            IDs.Shoulders = find(strcmp(app.Slot,"Shoulders")); Nums.Shoulders = numel(IDs.Shoulders);
            IDs.Back = find(strcmp(app.Slot,"Back")); Nums.Back = numel(IDs.Back);
            IDs.Chest = find(strcmp(app.Slot,"Chest")); Nums.Chest = numel(IDs.Chest);
            IDs.Wrists = find(strcmp(app.Slot,"Wrists")); Nums.Wrists = numel(IDs.Wrists);
            
            IDs.Hands = find(strcmp(app.Slot,"Hands")); Nums.Hands = numel(IDs.Hands);
            IDs.Waist = find(strcmp(app.Slot,"Waist")); Nums.Waist = numel(IDs.Waist);
            IDs.Legs = find(strcmp(app.Slot,"Legs")); Nums.Legs = numel(IDs.Legs);
            IDs.Feet = find(strcmp(app.Slot,"Feet")); Nums.Feet = numel(IDs.Feet);
            IDs.Finger = find(strcmp(app.Slot,"Finger")); Nums.Finger = numel(IDs.Finger);
            
            IDs.Weapon = find(strcmp(app.Slot,"Weapon")); Nums.Weapon = numel(IDs.Weapon);
            IDs.Wand = find(strcmp(app.Slot,"Wand")); Nums.Wand = numel(IDs.Wand);
            
            nSlots = numel(fields(IDs)) + 1;
            ArrayInds = ones(nSlots,1);
            
            temp = table2array(struct2table(Nums));
            MaxCombos = prod(temp(1:10)) * nchoosek(temp(11),2) * prod(temp(12:end));
            
            Combo = cell(MaxCombos,1);
            ComboDPS = zeros(MaxCombos,1);
            
            counter = 1; % total counter
            for i1=1:Nums.Head
            for i2=1:Nums.Neck
            for i3=1:Nums.Shoulders
            for i4=1:Nums.Back
            for i5=1:Nums.Chest
            for i6=1:Nums.Wrists
            for i7=1:Nums.Hands
            for i8=1:Nums.Waist
            for i9=1:Nums.Legs
            for i10=1:Nums.Feet
            for i11=1:Nums.Finger
            for i12=1:Nums.Finger
            for i13=1:Nums.Weapon
            for i14=1:Nums.Wand
            
            if (counter/MaxCombos > (app.OptimizeGauge.Value+0.01))
                app.OptimizeGauge.Value = app.OptimizeGauge.Value + 0.01;
                drawnow
            end
                
            ArrayInds(1) = i1;
            ArrayInds(2) = i2;
            ArrayInds(3) = i3;
            ArrayInds(4) = i4;
            ArrayInds(5) = i5;
            ArrayInds(6) = i6;
            ArrayInds(7) = i7;
            ArrayInds(8) = i8;
            ArrayInds(9) = i9;
            ArrayInds(10) = i10;
            ArrayInds(11) = i11; % Ring 1
            ArrayInds(12) = i12; % Ring 2
            ArrayInds(13) = i13;
            ArrayInds(14) = i14;
            
                if (i12 > i11)
                    
                Combo{counter} = ArrayInds;
                
                GearInt = sum(app.Int([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
                    IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
                    IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Finger(ArrayInds(11)) IDs.Finger(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
                    IDs.Wand(ArrayInds(14))])) + app.Trinket1Int.Value + app.Trinket2Int.Value + app.BaseInt;
                
                GearSP = sum(app.SP([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
                    IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
                    IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Finger(ArrayInds(11)) IDs.Finger(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
                    IDs.Wand(ArrayInds(14))])) + app.Trinket1SP.Value + app.Trinket2SP.Value;
                
                GearHit = sum(app.Hit([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
                    IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
                    IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Finger(ArrayInds(11)) IDs.Finger(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
                    IDs.Wand(ArrayInds(14))])) + app.Trinket1Hit.Value + app.Trinket2Hit.Value;
                
                GearCrit = sum(app.Crit([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
                    IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
                    IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Finger(ArrayInds(11)) IDs.Finger(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
                    IDs.Wand(ArrayInds(14))])) + app.Trinket1Crit.Value + app.Trinket2Crit.Value + app.BaseCrit;
                
                [~,ComboSP,ComboHit,ComboCrit,ComboMana] = GetTotals(app, ...
                                      GearInt,GearSP,GearHit,GearCrit, ...
                 isequal([IDs.Head(ArrayInds(1)) IDs.Shoulders(ArrayInds(3)) ...
                          IDs.Chest(ArrayInds(5)) IDs.Legs(ArrayInds(9)) ...
                          IDs.Feet(ArrayInds(10))], app.AQSetBonusIDs));
                
                 if (app.UsewiggleroomCheckBox.Value)
                    DPSOverWiggle = zeros(5,1);                    
                    for i=1:numel(DPSOverWiggle)
                        DPSOverWiggle(i) = ...
                         CalculateDPS(app, ...
                                      ComboSP,ComboHit, ...
                                      ComboCrit,ComboMana, ...
                                      app.FightLengthsecondsEditField.Value+2*(i-3));
                    end
                    ComboDPS(counter) = mean(DPSOverWiggle);
                else
                    ComboDPS(counter) = CalculateDPS(app, ...
                                  ComboSP,ComboHit, ...
                                  ComboCrit,ComboMana, ...
                                  app.FightLengthsecondsEditField.Value);
                end
                                         
                counter = counter + 1;
                end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            
            MaxInd = find(ComboDPS == max(ComboDPS));
            
            app.HeadDropDown.Value = app.Name(IDs.Head(Combo{MaxInd}(1)));
            HeadDropDownValueChanged(app,event)
            app.NeckDropDown.Value = app.Name(IDs.Neck(Combo{MaxInd}(2)));
            NeckDropDownValueChanged(app,event)
            app.ShouldersDropDown.Value = app.Name(IDs.Shoulders(Combo{MaxInd}(3)));
            ShouldersDropDownValueChanged(app,event)
            app.BackDropDown.Value = app.Name(IDs.Back(Combo{MaxInd}(4)));
            BackDropDownValueChanged(app,event)
            app.ChestDropDown.Value = app.Name(IDs.Chest(Combo{MaxInd}(5)));
            ChestDropDownValueChanged(app,event)
            app.WristsDropDown.Value = app.Name(IDs.Wrists(Combo{MaxInd}(6)));
            WristsDropDownValueChanged(app,event)
            app.HandsDropDown.Value = app.Name(IDs.Hands(Combo{MaxInd}(7)));
            HandsDropDownValueChanged(app,event)
            app.WaistDropDown.Value = app.Name(IDs.Waist(Combo{MaxInd}(8)));
            WaistDropDownValueChanged(app,event)
            app.LegsDropDown.Value = app.Name(IDs.Legs(Combo{MaxInd}(9)));
            LegsDropDownValueChanged(app,event)
            app.FeetDropDown.Value = app.Name(IDs.Feet(Combo{MaxInd}(10)));
            FeetDropDownValueChanged(app,event)
            app.Finger1DropDown.Value = app.Name(IDs.Finger(Combo{MaxInd}(11)));
            Finger1DropDownValueChanged(app,event)
            app.Finger2DropDown.Value = app.Name(IDs.Finger(Combo{MaxInd}(12)));
            Finger2DropDownValueChanged(app,event)
            app.WeaponDropDown.Value = app.Name(IDs.Weapon(Combo{MaxInd}(13)));
            WeaponDropDownValueChanged(app,event)
            app.WandDropDown.Value = app.Name(IDs.Wand(Combo{MaxInd}(14)));
            WandDropDownValueChanged(app,event)
            % Final changed event triggers UpdateTotals and CalculateDPS
            
            app.OptimizeGauge.Value = 0;
        end

        % Button pushed function: FullOptimizeButton
        function FullOptimizeButtonPushed(app, event)
            IDs.Head = find(strcmp(app.Slot,"Head")); Nums.Head = numel(IDs.Head);
            IDs.Neck = find(strcmp(app.Slot,"Neck")); Nums.Neck = numel(IDs.Neck);
            IDs.Shoulders = find(strcmp(app.Slot,"Shoulders")); Nums.Shoulders = numel(IDs.Shoulders);
            IDs.Back = find(strcmp(app.Slot,"Back")); Nums.Back = numel(IDs.Back);
            IDs.Chest = find(strcmp(app.Slot,"Chest")); Nums.Chest = numel(IDs.Chest);
            IDs.Wrists = find(strcmp(app.Slot,"Wrists")); Nums.Wrists = numel(IDs.Wrists);
            
            IDs.Hands = find(strcmp(app.Slot,"Hands")); Nums.Hands = numel(IDs.Hands);
            IDs.Waist = find(strcmp(app.Slot,"Waist")); Nums.Waist = numel(IDs.Waist);
            IDs.Legs = find(strcmp(app.Slot,"Legs")); Nums.Legs = numel(IDs.Legs);
            IDs.Feet = find(strcmp(app.Slot,"Feet")); Nums.Feet = numel(IDs.Feet);
            IDs.Finger = find(strcmp(app.Slot,"Finger")); Nums.Finger = numel(IDs.Finger);
            
            IDs.Weapon = find(strcmp(app.Slot,"Weapon")); Nums.Weapon = numel(IDs.Weapon);
            IDs.Wand = find(strcmp(app.Slot,"Wand")); Nums.Wand = numel(IDs.Wand);
            
            nSlots = numel(fields(IDs)) + 1;
            ArrayInds = ones(nSlots,1);
            
            temp = table2array(struct2table(Nums));
            MaxCombos = prod(temp(1:10)) * nchoosek(temp(11),2) * prod(temp(12:end));
            
            oldc = app.PreferenceSlider.Value;
            nPerProbMesh = app.MeshPointsSpinner.Value;
            PerProbMesh = linspace(app.FromSpinner.Value/100, ...
                                   app.ToSpinner.Value/100, ...
                                   nPerProbMesh);
            maxComboDPSPerProb = zeros(nPerProbMesh,1);
            
            for k=1:nPerProbMesh
                
            Combo = cell(MaxCombos,1);
            ComboDPS = zeros(MaxCombos,1);
            app.PreferenceSlider.Value = PerProbMesh(k);
            
            counter = 1; % total counter
            for i1=1:Nums.Head
            for i2=1:Nums.Neck
            for i3=1:Nums.Shoulders
            for i4=1:Nums.Back
            for i5=1:Nums.Chest
            for i6=1:Nums.Wrists
            for i7=1:Nums.Hands
            for i8=1:Nums.Waist
            for i9=1:Nums.Legs
            for i10=1:Nums.Feet
            for i11=1:Nums.Finger
            for i12=1:Nums.Finger
            for i13=1:Nums.Weapon
            for i14=1:Nums.Wand
            
            if (k*counter/(MaxCombos*nPerProbMesh) > (app.FullOptimizeGauge.Value+0.01))
                app.FullOptimizeGauge.Value = app.FullOptimizeGauge.Value + 0.01;
                drawnow
            end
                
            ArrayInds(1) = i1;
            ArrayInds(2) = i2;
            ArrayInds(3) = i3;
            ArrayInds(4) = i4;
            ArrayInds(5) = i5;
            ArrayInds(6) = i6;
            ArrayInds(7) = i7;
            ArrayInds(8) = i8;
            ArrayInds(9) = i9;
            ArrayInds(10) = i10;
            ArrayInds(11) = i11; % Ring 1
            ArrayInds(12) = i12; % Ring 2
            ArrayInds(13) = i13;
            ArrayInds(14) = i14;
            
                if (i12 > i11)
                    
                Combo{counter} = ArrayInds;
                
                GearInt = sum(app.Int([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
                    IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
                    IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Finger(ArrayInds(11)) IDs.Finger(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
                    IDs.Wand(ArrayInds(14))])) + app.Trinket1Int.Value + app.Trinket2Int.Value + app.BaseInt;
                
                GearSP = sum(app.SP([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
                    IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
                    IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Finger(ArrayInds(11)) IDs.Finger(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
                    IDs.Wand(ArrayInds(14))])) + app.Trinket1SP.Value + app.Trinket2SP.Value;
                
                GearHit = sum(app.Hit([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
                    IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
                    IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Finger(ArrayInds(11)) IDs.Finger(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
                    IDs.Wand(ArrayInds(14))])) + app.Trinket1Hit.Value + app.Trinket2Hit.Value;
                
                GearCrit = sum(app.Crit([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
                    IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
                    IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Finger(ArrayInds(11)) IDs.Finger(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
                    IDs.Wand(ArrayInds(14))])) + app.Trinket1Crit.Value + app.Trinket2Crit.Value + app.BaseCrit;
                
                [~,ComboSP,ComboHit,ComboCrit,ComboMana] = GetTotals(app, ...
                                      GearInt,GearSP,GearHit,GearCrit, ...
                 isequal([IDs.Head(ArrayInds(1)) IDs.Shoulders(ArrayInds(3)) ...
                          IDs.Chest(ArrayInds(5)) IDs.Legs(ArrayInds(9)) ...
                          IDs.Feet(ArrayInds(10))], app.AQSetBonusIDs));
                
                ComboDPS(counter) = CalculateDPS(app,ComboSP, ...
                                             ComboHit,ComboCrit,ComboMana);
                counter = counter + 1;
                end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            
            maxComboDPSPerProb(k) = max(ComboDPS);
            end
            
            app.PreferenceSlider.Value = oldc;
            plot(app.FullOptimizePlot,PerProbMesh,maxComboDPSPerProb)
            
            app.FullOptimizeGauge.Value = 0;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            UpdateTotals(app)
            UpdateDPS(app)
        end

        % Value changed function: SaveDPSCheckBox
        function SaveDPSCheckBoxValueChanged(app, event)
            if (app.SaveDPSCheckBox.Value)
                app.SavedDPSEditField.Value = app.EstimatedDPSEditField.Value;
                app.SavedDifference.Value = app.EstimatedDPSEditField.Value - app.SavedDPSEditField.Value;
                app.SavedRelativeDifference.Value = string(round(app.SavedDifference.Value/app.SavedDPSEditField.Value,2)*100) + "%";
                
                if (app.SavedDifference.Value == 0)
                    app.SavedDifference.FontColor = [0 0 0];
                    app.SavedRelativeDifference.FontColor = [0 0 0];
                end
                
                if (app.SavedDifference.Value > 0)
                    app.SavedDifference.FontColor = [0.47,0.67,0.19];
                    app.SavedRelativeDifference.FontColor = [0.47,0.67,0.19];
                end
                
                if (app.SavedDifference.Value < 0)
                    app.SavedDifference.FontColor = [0.64,0.08,0.18];
                    app.SavedRelativeDifference.FontColor = [0.64,0.08,0.18];
                end
            else
                app.SavedDPSEditField.Value = 0;
                app.SavedDifference.Value = 0;
                app.SavedRelativeDifference.Value = "0";
                app.SavedDifference.FontColor = [0 0 0];
                app.SavedRelativeDifference.FontColor = [0 0 0];
            end
        end

        % Value changing function: PreferenceSlider
        function PreferenceSliderValueChanging(app, event)
            %UpdateDPS(app)
        end

        % Value changed function: PreferenceSlider
        function PreferenceSliderValueChanged(app, event)
            UpdateDPS(app)
        end

        % Value changed function: Rank10ShadowboltCheckBox
        function Rank10ShadowboltCheckBoxValueChanged(app, event)
            if (app.Rank10ShadowboltCheckBox.Value)
                app.SBoltCost = 380;
                app.BaseSBoltDmgUB = 538;
                app.BaseSBoltDmgLB = 482;
            else % Rank 9, default
                app.SBoltCost = 370;
                app.BaseSBoltDmgUB = 507;
                app.BaseSBoltDmgLB = 455;
            end
            app.BaseSBoltDmg = (app.BaseSBoltDmgLB+app.BaseSBoltDmgUB)/2;
            
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: Rank7Corruption
        function Rank7CorruptionValueChanged(app, event)
            if (app.Rank7Corruption.Value)
                app.BaseCorrDamage = 822;
                app.CorrMana = 340;
            else % Rank 6
                app.BaseCorrDamage = 666;
                app.CorrMana = 290;
            end
            app.BaseSBoltDmg = (app.BaseSBoltDmgLB+app.BaseSBoltDmgUB)/2;
            
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: Rank8Immolate
        function Rank8ImmolateValueChanged(app, event)
            if (app.Rank8Immolate.Value)
                app.BaseImmoDirectDamage = 279;
                app.BaseImmoDoTDamage = 510;
                app.ImmoMana = 380;
            else % Rank 7
                app.BaseImmoDirectDamage = 254;
                app.BaseImmoDoTDamage = 485;
                app.ImmoMana = 370;
            end
            app.BaseSBoltDmg = (app.BaseSBoltDmgLB+app.BaseSBoltDmgUB)/2;
            
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: TalentTreeDropDown
        function TalentTreeDropDownValueChanged(app, event)
            switch app.TalentTreeDropDown.Value
                case "Mana-efficient Destro Sac"
                    app.ImprovedCorruptionPoints.Value = 5;
                    app.CataclysmPoints.Value = 5;
                    app.BanePoints.Value = 5;
                    app.DevastationPoints.Value = 5;
                    app.RuinPoints.Value = 1;
                    app.SacPoints.Value = 1;
                    app.ImprovedLifetapPoints.Value = 2;
                    app.ShadowMasteryPoints.Value = 0;
                case "Mana-inefficient Destro Sac"
                    app.ImprovedCorruptionPoints.Value = 0;
                    app.CataclysmPoints.Value = 0;
                    app.BanePoints.Value = 5;
                    app.DevastationPoints.Value = 5;
                    app.RuinPoints.Value = 1;
                    app.SacPoints.Value = 1;
                    app.ImprovedLifetapPoints.Value = 0;
                    app.ShadowMasteryPoints.Value = 0;
                case "SM Sac"
                    app.ImprovedCorruptionPoints.Value = 0;
                    app.CataclysmPoints.Value = 0;
                    app.BanePoints.Value = 0;
                    app.DevastationPoints.Value = 0;
                    app.RuinPoints.Value = 0;
                    app.SacPoints.Value = 1;
                    app.ImprovedLifetapPoints.Value = 2;
                    app.ShadowMasteryPoints.Value = 5;
                case "Mana-efficient SM Ruin"
                    app.ImprovedCorruptionPoints.Value = 5;
                    app.CataclysmPoints.Value = 5;
                    app.BanePoints.Value = 5;
                    app.DevastationPoints.Value = 5;
                    app.RuinPoints.Value = 1;
                    app.SacPoints.Value = 0;
                    app.ImprovedLifetapPoints.Value = 2;
                    app.ShadowMasteryPoints.Value = 5;
                case "Mana-inefficient SM Ruin"
                    app.ImprovedCorruptionPoints.Value = 5;
                    app.CataclysmPoints.Value = 0;
                    app.BanePoints.Value = 5;
                    app.DevastationPoints.Value = 5;
                    app.RuinPoints.Value = 1;
                    app.SacPoints.Value = 0;
                    app.ImprovedLifetapPoints.Value = 2;
                    app.ShadowMasteryPoints.Value = 5;
            end
            
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: HeadDropDown
        function HeadDropDownValueChanged(app, event)
            CurrentItemValues = [app.HeadInt.Value app.HeadSP.Value app.HeadHit.Value app.HeadCrit.Value];
            CurrentItemName = app.HeadDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];

            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.HeadInt.Value = DatabaseItemValues(1);
                app.HeadSP.Value = DatabaseItemValues(2);
                app.HeadHit.Value = DatabaseItemValues(3);
                app.HeadCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: NeckDropDown
        function NeckDropDownValueChanged(app, event)
            CurrentItemValues = [app.NeckInt.Value app.NeckSP.Value app.NeckHit.Value app.NeckCrit.Value];
            CurrentItemName = app.NeckDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.NeckInt.Value = DatabaseItemValues(1);
                app.NeckSP.Value = DatabaseItemValues(2);
                app.NeckHit.Value = DatabaseItemValues(3);
                app.NeckCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ShouldersDropDown
        function ShouldersDropDownValueChanged(app, event)
            CurrentItemValues = [app.ShouldersInt.Value app.ShouldersSP.Value app.ShouldersHit.Value app.ShouldersCrit.Value];
            CurrentItemName = app.ShouldersDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.ShouldersInt.Value = DatabaseItemValues(1);
                app.ShouldersSP.Value = DatabaseItemValues(2);
                app.ShouldersHit.Value = DatabaseItemValues(3);
                app.ShouldersCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: BackDropDown
        function BackDropDownValueChanged(app, event)
            CurrentItemValues = [app.BackInt.Value app.BackSP.Value app.BackHit.Value app.BackCrit.Value];
            CurrentItemName = app.BackDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.BackInt.Value = DatabaseItemValues(1);
                app.BackSP.Value = DatabaseItemValues(2);
                app.BackHit.Value = DatabaseItemValues(3);
                app.BackCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ChestDropDown
        function ChestDropDownValueChanged(app, event)
            CurrentItemValues = [app.ChestInt.Value app.ChestSP.Value app.ChestHit.Value app.ChestCrit.Value];
            CurrentItemName = app.ChestDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.ChestInt.Value = DatabaseItemValues(1);
                app.ChestSP.Value = DatabaseItemValues(2);
                app.ChestHit.Value = DatabaseItemValues(3);
                app.ChestCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: WristsDropDown
        function WristsDropDownValueChanged(app, event)
            CurrentItemValues = [app.WristsInt.Value app.WristsSP.Value app.WristsHit.Value app.WristsCrit.Value];
            CurrentItemName = app.WristsDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.WristsInt.Value = DatabaseItemValues(1);
                app.WristsSP.Value = DatabaseItemValues(2);
                app.WristsHit.Value = DatabaseItemValues(3);
                app.WristsCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: HandsDropDown
        function HandsDropDownValueChanged(app, event)
            CurrentItemValues = [app.HandsInt.Value app.HandsSP.Value app.HandsHit.Value app.HandsCrit.Value];
            CurrentItemName = app.HandsDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.HandsInt.Value = DatabaseItemValues(1);
                app.HandsSP.Value = DatabaseItemValues(2);
                app.HandsHit.Value = DatabaseItemValues(3);
                app.HandsCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: WaistDropDown
        function WaistDropDownValueChanged(app, event)
            CurrentItemValues = [app.WaistInt.Value app.WaistSP.Value app.WaistHit.Value app.WaistCrit.Value];
            CurrentItemName = app.WaistDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.WaistInt.Value = DatabaseItemValues(1);
                app.WaistSP.Value = DatabaseItemValues(2);
                app.WaistHit.Value = DatabaseItemValues(3);
                app.WaistCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: LegsDropDown
        function LegsDropDownValueChanged(app, event)
            CurrentItemValues = [app.LegsInt.Value app.LegsSP.Value app.LegsHit.Value app.LegsCrit.Value];
            CurrentItemName = app.LegsDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.LegsInt.Value = DatabaseItemValues(1);
                app.LegsSP.Value = DatabaseItemValues(2);
                app.LegsHit.Value = DatabaseItemValues(3);
                app.LegsCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: FeetDropDown
        function FeetDropDownValueChanged(app, event)
            CurrentItemValues = [app.FeetInt.Value app.FeetSP.Value app.FeetHit.Value app.FeetCrit.Value];
            CurrentItemName = app.FeetDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.FeetInt.Value = DatabaseItemValues(1);
                app.FeetSP.Value = DatabaseItemValues(2);
                app.FeetHit.Value = DatabaseItemValues(3);
                app.FeetCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: Finger1DropDown
        function Finger1DropDownValueChanged(app, event)
            CurrentItemValues = [app.Finger1Int.Value app.Finger1SP.Value app.Finger1Hit.Value app.Finger1Crit.Value];
            CurrentItemName = app.Finger1DropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.Finger1Int.Value = DatabaseItemValues(1);
                app.Finger1SP.Value = DatabaseItemValues(2);
                app.Finger1Hit.Value = DatabaseItemValues(3);
                app.Finger1Crit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: Finger2DropDown
        function Finger2DropDownValueChanged(app, event)
            CurrentItemValues = [app.Finger2Int.Value app.Finger2SP.Value app.Finger2Hit.Value app.Finger2Crit.Value];
            CurrentItemName = app.Finger2DropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.Finger2Int.Value = DatabaseItemValues(1);
                app.Finger2SP.Value = DatabaseItemValues(2);
                app.Finger2Hit.Value = DatabaseItemValues(3);
                app.Finger2Crit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: WeaponDropDown
        function WeaponDropDownValueChanged(app, event)
            CurrentItemValues = [app.WeaponInt.Value app.WeaponSP.Value app.WeaponHit.Value app.WeaponCrit.Value];
            CurrentItemName = app.WeaponDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.WeaponInt.Value = DatabaseItemValues(1);
                app.WeaponSP.Value = DatabaseItemValues(2);
                app.WeaponHit.Value = DatabaseItemValues(3);
                app.WeaponCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: WandDropDown
        function WandDropDownValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ShadowElixirCheckBox
        function ShadowElixirCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: GreaterArcaneElixirCheckBox
        function GreaterArcaneElixirCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: FlaskofSupremePowerCheckBox
        function FlaskofSupremePowerCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: DMTCheckBox
        function DMTCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: OnyCheckBox
        function OnyCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: HakkarCheckBox
        function HakkarCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: SongflowerCheckBox
        function SongflowerCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: BrilliantWizardOilCheckBox
        function BrilliantWizardOilCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: MoonkinAuraCheckBox
        function MoonkinAuraCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: BlastedLandsIntCheckBox
        function BlastedLandsIntCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ElixiroftheSagesCheckBox
        function ElixiroftheSagesCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ArcaneIntellectCheckBox
        function ArcaneIntellectCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: FestivalSausageCheckBox
        function FestivalSausageCheckBoxValueChanged(app, event)
            if (app.TubersCheckBox.Value)
                app.TubersCheckBox.Value = 0;
            end
            
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: TubersCheckBox
        function TubersCheckBoxValueChanged(app, event)
            if (app.FestivalSausageCheckBox.Value)
                app.FestivalSausageCheckBox.Value = 0;
            end
            
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ManaPotionsCheckBox
        function ManaPotionsCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: DemonicRunesCheckBox
        function DemonicRunesCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ManaTideCheckBox
        function ManaTideCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ManaSpringCheckBox
        function ManaSpringCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: MagebloodCheckBox
        function MagebloodCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: RendCheckBox
        function RendCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: HeadEnchant
        function HeadEnchantValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ShouldersEnchant
        function ShouldersEnchantValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ChestEnchant
        function ChestEnchantValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: WristsEnchant
        function WristsEnchantValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: WeaponEnchant
        function WeaponEnchantValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: HandsEnchant
        function HandsEnchantValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: LegsEnchant
        function LegsEnchantValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: DMFCheckBox
        function DMFCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ShadowWeavingCheckBox
        function ShadowWeavingCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: CurseofShadowsCheckBox
        function CurseofShadowsCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: CurseofAgonyCheckBox
        function CurseofAgonyCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: CurseofDoomCheckBox
        function CurseofDoomCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ImmolateCheckBox
        function ImmolateCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: CorruptionCheckBox
        function CorruptionCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: SoulFireCheckBox
        function SoulFireCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ClipDoTsCheckBox
        function ClipDoTsCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: MarkoftheWildCheckBox
        function MarkoftheWildCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: SelectAllBuffsConsumesCheckBox
        function SelectAllBuffsConsumesCheckBoxValueChanged(app, event)
            if (app.SelectAllBuffsConsumesCheckBox.Value)
                app.RendCheckBox.Value = 1;
                app.MagebloodCheckBox.Value = 1;
                app.ManaSpringCheckBox.Value = 1;
                app.ManaTideCheckBox.Value = 1;
                app.DemonicRunesCheckBox.Value = 1;
                app.ManaPotionsCheckBox.Value = 1;
                app.ArcaneIntellectCheckBox.Value = 1;
                app.MarkoftheWildCheckBox.Value = 1;
                app.TubersCheckBox.Value = 1;
                app.ElixiroftheSagesCheckBox.Value = 1;
                app.BlastedLandsIntCheckBox.Value = 1;
                app.MoonkinAuraCheckBox.Value = 1;
                app.Rank10ShadowboltCheckBox.Value = 1;
                app.BrilliantWizardOilCheckBox.Value = 1;
                app.SongflowerCheckBox.Value = 1;
                app.HakkarCheckBox.Value = 1;
                app.OnyCheckBox.Value = 1;
                app.DMTCheckBox.Value = 1;
                app.FlaskofSupremePowerCheckBox.Value = 1;
                app.GreaterArcaneElixirCheckBox.Value = 1;
                app.ShadowElixirCheckBox.Value = 1;
                app.DMFCheckBox.Value = 1;
                app.ShadowWeavingCheckBox.Value = 1;
                app.CurseofShadowsCheckBox.Value = 1;
            else
                app.RendCheckBox.Value = 0;
                app.MagebloodCheckBox.Value = 0;
                app.ManaSpringCheckBox.Value = 0;
                app.ManaTideCheckBox.Value = 0;
                app.DemonicRunesCheckBox.Value = 0;
                app.ManaPotionsCheckBox.Value = 0;
                app.ArcaneIntellectCheckBox.Value = 0;
                app.MarkoftheWildCheckBox.Value = 0;
                app.TubersCheckBox.Value = 0;
                app.ElixiroftheSagesCheckBox.Value = 0;
                app.BlastedLandsIntCheckBox.Value = 0;
                app.MoonkinAuraCheckBox.Value = 0;
                app.Rank10ShadowboltCheckBox.Value = 0;
                app.BrilliantWizardOilCheckBox.Value = 0;
                app.SongflowerCheckBox.Value = 0;
                app.HakkarCheckBox.Value = 0;
                app.OnyCheckBox.Value = 0;
                app.DMTCheckBox.Value = 0;
                app.FlaskofSupremePowerCheckBox.Value = 0;
                app.GreaterArcaneElixirCheckBox.Value = 0;
                app.ShadowElixirCheckBox.Value = 0;
                app.DMFCheckBox.Value = 0;
                app.ShadowWeavingCheckBox.Value = 0;
                app.CurseofShadowsCheckBox.Value = 0;
            end
            DisableOnetimeBuffsCheckBoxValueChanged(app,event)
            Rank10ShadowboltCheckBoxValueChanged(app,event);
            
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: DisableOnetimeBuffsCheckBox
        function DisableOnetimeBuffsCheckBoxValueChanged(app, event)
            if (app.DisableOnetimeBuffsCheckBox.Value)
                app.RendCheckBox.Value = 0;
                app.BlastedLandsIntCheckBox.Value = 0;
                app.SongflowerCheckBox.Value = 0;
                app.HakkarCheckBox.Value = 0;
                app.OnyCheckBox.Value = 0;
                app.DMTCheckBox.Value = 0;
                app.DMFCheckBox.Value = 0;
                
                app.RendCheckBox.Enable = 0;
                app.BlastedLandsIntCheckBox.Enable = 0;
                app.SongflowerCheckBox.Enable = 0;
                app.HakkarCheckBox.Enable = 0;
                app.OnyCheckBox.Enable = 0;
                app.DMTCheckBox.Enable = 0;
                app.DMFCheckBox.Enable = 0;
            else
                app.RendCheckBox.Enable = 1;
                app.BlastedLandsIntCheckBox.Enable = 1;
                app.SongflowerCheckBox.Enable = 1;
                app.HakkarCheckBox.Enable = 1;
                app.OnyCheckBox.Enable = 1;
                app.DMTCheckBox.Enable = 1;
                app.DMFCheckBox.Enable = 1;
            end
            Rank10ShadowboltCheckBoxValueChanged(app,event);
            
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: SelectAllEnchantsCheckBox
        function SelectAllEnchantsCheckBoxValueChanged(app, event)
            if (app.SelectAllEnchantsCheckBox.Value)
                app.HeadEnchant.Value = 1;
                app.ShouldersEnchant.Value = 1;
                app.ChestEnchant.Value = 1;
                app.WristsEnchant.Value = 1;
                app.HandsEnchant.Value = 1;
                app.LegsEnchant.Value = 1;
                app.WeaponEnchant.Value = 1;
            else
                app.HeadEnchant.Value = 0;
                app.ShouldersEnchant.Value = 0;
                app.ChestEnchant.Value = 0;
                app.WristsEnchant.Value = 0;
                app.HandsEnchant.Value = 0;
                app.LegsEnchant.Value = 0;
                app.WeaponEnchant.Value = 0;
            end
            Rank10ShadowboltCheckBoxValueChanged(app,event);
            
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: RDPSPCheckBox
        function RDPSPCheckBoxValueChanged(app, event)
            if (app.RDPSPCheckBox.Value)
                app.DPSCheckBox.Value = 0;
            else
                app.DPSCheckBox.Value = 1;
            end
            
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: DPSCheckBox
        function DPSCheckBoxValueChanged(app, event)
            if (app.DPSCheckBox.Value)
                app.RDPSPCheckBox.Value = 0;
            else
                app.RDPSPCheckBox.Value = 1;
            end
            
            UpdateTotals(app)
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ToSpinner
        function ToSpinnerValueChanged(app, event)
            if (app.ToSpinner.Value < app.FromSpinner.Value)
                app.ToSpinner.Value = app.FromSpinner.Value;
            end
        end

        % Value changed function: FromSpinner
        function FromSpinnerValueChanged(app, event)
            if (app.ToSpinner.Value > app.FromSpinner.Value)
                app.ToSpinner.Value = app.FromSpinner.Value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GearAndDPS = uitab(app.TabGroup);
            app.GearAndDPS.Title = 'Gear and Buffs';
            app.NeckInt = uieditfield(app.GearAndDPS, 'numeric');
            app.NeckInt.Value = 16;
            app.NeckSP = uieditfield(app.GearAndDPS, 'numeric');
            app.NeckHit = uieditfield(app.GearAndDPS, 'numeric');
            app.NeckHit.Value = 1;
            app.NeckCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.ShouldersInt = uieditfield(app.GearAndDPS, 'numeric');
            app.ShouldersInt.Value = 11;
            app.ShouldersSP = uieditfield(app.GearAndDPS, 'numeric');
            app.ShouldersSP.Value = 28;
            app.ShouldersHit = uieditfield(app.GearAndDPS, 'numeric');
            app.ShouldersHit.Value = 1;
            app.ShouldersCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.BackInt = uieditfield(app.GearAndDPS, 'numeric');
            app.BackInt.Value = 10;
            app.BackSP = uieditfield(app.GearAndDPS, 'numeric');
            app.BackSP.Value = 30;
            app.BackHit = uieditfield(app.GearAndDPS, 'numeric');
            app.BackHit.Value = 1;
            app.BackCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.ChestInt = uieditfield(app.GearAndDPS, 'numeric');
            app.ChestInt.Value = 15;
            app.ChestSP = uieditfield(app.GearAndDPS, 'numeric');
            app.ChestSP.Value = 23;
            app.ChestHit = uieditfield(app.GearAndDPS, 'numeric');
            app.ChestCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.ChestCrit.Value = 2;
            app.WristsInt = uieditfield(app.GearAndDPS, 'numeric');
            app.WristsInt.Value = 13;
            app.WristsSP = uieditfield(app.GearAndDPS, 'numeric');
            app.WristsSP.Value = 28;
            app.WristsHit = uieditfield(app.GearAndDPS, 'numeric');
            app.WristsCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger1Int = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger1Int.Value = 12;
            app.Finger1SP = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger1SP.Value = 21;
            app.Finger1Hit = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger1Hit.Value = 1;
            app.Finger1Crit = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger2Int = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger2SP = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger2SP.Value = 33;
            app.Finger2Hit = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger2Crit = uieditfield(app.GearAndDPS, 'numeric');
            app.WaistInt = uieditfield(app.GearAndDPS, 'numeric');
            app.WaistInt.Value = 9;
            app.WaistSP = uieditfield(app.GearAndDPS, 'numeric');
            app.WaistSP.Value = 41;
            app.WaistHit = uieditfield(app.GearAndDPS, 'numeric');
            app.WaistCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.WaistCrit.Value = 1;
            app.LegsInt = uieditfield(app.GearAndDPS, 'numeric');
            app.LegsInt.Value = 16;
            app.LegsSP = uieditfield(app.GearAndDPS, 'numeric');
            app.LegsSP.Value = 41;
            app.LegsHit = uieditfield(app.GearAndDPS, 'numeric');
            app.LegsCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.LegsCrit.Value = 1;
            app.FeetInt = uieditfield(app.GearAndDPS, 'numeric');
            app.FeetInt.Value = 19;
            app.FeetSP = uieditfield(app.GearAndDPS, 'numeric');
            app.FeetSP.Value = 34;
            app.FeetHit = uieditfield(app.GearAndDPS, 'numeric');
            app.FeetCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket1Int = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket1SP = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket1SP.Value = 11.73333;
            app.Trinket1Hit = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket1Crit = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket2Int = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket2SP = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket2SP.Value = 44;
            app.Trinket2Hit = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket2Hit.Value = 2;
            app.Trinket2Crit = uieditfield(app.GearAndDPS, 'numeric');
            app.WandInt = uieditfield(app.GearAndDPS, 'numeric');
            app.WandSP = uieditfield(app.GearAndDPS, 'numeric');
            app.WandSP.Value = 19;
            app.WandHit = uieditfield(app.GearAndDPS, 'numeric');
            app.WandCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.WeaponInt = uieditfield(app.GearAndDPS, 'numeric');
            app.WeaponInt.Value = 33;
            app.WeaponSP = uieditfield(app.GearAndDPS, 'numeric');
            app.WeaponSP.Value = 76;
            app.WeaponHit = uieditfield(app.GearAndDPS, 'numeric');
            app.WeaponHit.Value = 2;
            app.WeaponCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.WeaponCrit.Value = 1;
            app.OffhandInt = uieditfield(app.GearAndDPS, 'numeric');
            app.OffhandSP = uieditfield(app.GearAndDPS, 'numeric');
            app.OffhandHit = uieditfield(app.GearAndDPS, 'numeric');
            app.OffhandCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.HeadDropDown_2Label = uilabel(app.GearAndDPS);
            app.HeadDropDown_2Label.Text = 'Head';
            app.HeadDropDown = uidropdown(app.GearAndDPS);
            app.HeadDropDown.Items = {'Doomcaller''s Circlet', 'Mish''undare', 'Hexer''s Cover'};
            app.HeadDropDown.ValueChangedFcn = createCallbackFcn(app, @HeadDropDownValueChanged, true);
            app.HeadDropDown.Value = 'Doomcaller''s Circlet';
            app.NeckDropDown_2Label = uilabel(app.GearAndDPS);
            app.NeckDropDown_2Label.Text = 'Neck';
            app.NeckDropDown = uidropdown(app.GearAndDPS);
            app.NeckDropDown.Items = {'Soul Corrupter''s Necklace', 'Amulet of Vek''nilash', 'Choker of the Fire Lord'};
            app.NeckDropDown.ValueChangedFcn = createCallbackFcn(app, @NeckDropDownValueChanged, true);
            app.NeckDropDown.Value = 'Soul Corrupter''s Necklace';
            app.ShouldersDropDown_2Label = uilabel(app.GearAndDPS);
            app.ShouldersDropDown_2Label.Text = 'Shoulders';
            app.ShouldersDropDown = uidropdown(app.GearAndDPS);
            app.ShouldersDropDown.Items = {'Doomcaller''s Mantle', 'Mantle of the Blackwing Cabal'};
            app.ShouldersDropDown.ValueChangedFcn = createCallbackFcn(app, @ShouldersDropDownValueChanged, true);
            app.ShouldersDropDown.Value = 'Doomcaller''s Mantle';
            app.ChestDropDown_2Label = uilabel(app.GearAndDPS);
            app.ChestDropDown_2Label.Text = 'Chest';
            app.ChestDropDown = uidropdown(app.GearAndDPS);
            app.ChestDropDown.Items = {'Robe of Volatile Power', 'Garb of Royal Ascension', 'Doomcaller''s Robes'};
            app.ChestDropDown.ValueChangedFcn = createCallbackFcn(app, @ChestDropDownValueChanged, true);
            app.ChestDropDown.Value = 'Robe of Volatile Power';
            app.WristsDropDownLabel = uilabel(app.GearAndDPS);
            app.WristsDropDownLabel.Text = 'Wrists';
            app.WristsDropDown = uidropdown(app.GearAndDPS);
            app.WristsDropDown.Items = {'Burrower Bracers', 'Rockfury Bracers', 'Bracers of Arcane Accuracy'};
            app.WristsDropDown.ValueChangedFcn = createCallbackFcn(app, @WristsDropDownValueChanged, true);
            app.WristsDropDown.Value = 'Burrower Bracers';
            app.HandsDropDown_2Label = uilabel(app.GearAndDPS);
            app.HandsDropDown_2Label.Text = 'Hands';
            app.HandsDropDown = uidropdown(app.GearAndDPS);
            app.HandsDropDown.Items = {'Dark Storm Gauntlets', 'Nemesis Gloves', 'Ebony Flame Gloves'};
            app.HandsDropDown.ValueChangedFcn = createCallbackFcn(app, @HandsDropDownValueChanged, true);
            app.HandsDropDown.Value = 'Dark Storm Gauntlets';
            app.WaistDropDown_2Label = uilabel(app.GearAndDPS);
            app.WaistDropDown_2Label.Text = 'Waist';
            app.WaistDropDown = uidropdown(app.GearAndDPS);
            app.WaistDropDown.Items = {'Eyestalk Waistcord', 'Angelista''s Grasp'};
            app.WaistDropDown.ValueChangedFcn = createCallbackFcn(app, @WaistDropDownValueChanged, true);
            app.WaistDropDown.Value = 'Eyestalk Waistcord';
            app.LegsDropDown_2Label = uilabel(app.GearAndDPS);
            app.LegsDropDown_2Label.Text = 'Legs';
            app.LegsDropDown = uidropdown(app.GearAndDPS);
            app.LegsDropDown.Items = {'Leggings of the Black Blizzard', 'Doomcaller''s Trousers'};
            app.LegsDropDown.ValueChangedFcn = createCallbackFcn(app, @LegsDropDownValueChanged, true);
            app.LegsDropDown.Value = 'Leggings of the Black Blizzard';
            app.FeetDropDownLabel = uilabel(app.GearAndDPS);
            app.FeetDropDownLabel.Text = 'Feet';
            app.FeetDropDown = uidropdown(app.GearAndDPS);
            app.FeetDropDown.Items = {'Boots of Epiphany', 'Doomcaller''s Footwraps', 'Bloodvine Feet'};
            app.FeetDropDown.ValueChangedFcn = createCallbackFcn(app, @FeetDropDownValueChanged, true);
            app.FeetDropDown.Value = 'Boots of Epiphany';
            app.Finger1DropDownLabel = uilabel(app.GearAndDPS);
            app.Finger1DropDownLabel.Text = 'Finger 1';
            app.Finger1DropDown = uidropdown(app.GearAndDPS);
            app.Finger1DropDown.Items = {'Band of Forced Concentration', 'Ring of the Fallen God', 'Ritssyn''s Ring of Chaos', 'Charm of the Shifting Sands', 'Ring of Unspoken Names', 'Ring of Spell Power', 'Band of Dark Dominion'};
            app.Finger1DropDown.ValueChangedFcn = createCallbackFcn(app, @Finger1DropDownValueChanged, true);
            app.Finger1DropDown.Value = 'Band of Forced Concentration';
            app.Trinket1DropDown_2Label = uilabel(app.GearAndDPS);
            app.Trinket1DropDown_2Label.Text = 'Trinket 1';
            app.Trinket1DropDown = uidropdown(app.GearAndDPS);
            app.Trinket1DropDown.Items = {'Talisman of Ephemeral Power'};
            app.Trinket1DropDown.Value = 'Talisman of Ephemeral Power';
            app.Trinket2DropDown_2Label = uilabel(app.GearAndDPS);
            app.Trinket2DropDown_2Label.Text = 'Trinket 2';
            app.Trinket2DropDown = uidropdown(app.GearAndDPS);
            app.Trinket2DropDown.Items = {'Neltharion''s Tear'};
            app.Trinket2DropDown.Value = 'Neltharion''s Tear';
            app.WeaponDropDown_2Label = uilabel(app.GearAndDPS);
            app.WeaponDropDown_2Label.Text = 'Weapon';
            app.WeaponDropDown = uidropdown(app.GearAndDPS);
            app.WeaponDropDown.Items = {'Blessed Qiraji Acolyte Staff', 'Staff of the Shadowflame'};
            app.WeaponDropDown.ValueChangedFcn = createCallbackFcn(app, @WeaponDropDownValueChanged, true);
            app.WeaponDropDown.Value = 'Blessed Qiraji Acolyte Staff';
            app.WandDropDown_2Label = uilabel(app.GearAndDPS);
            app.WandDropDown_2Label.Text = 'Wand';
            app.WandDropDown = uidropdown(app.GearAndDPS);
            app.WandDropDown.Items = {'Wand of Qiraji Nobility'};
            app.WandDropDown.ValueChangedFcn = createCallbackFcn(app, @WandDropDownValueChanged, true);
            app.WandDropDown.Value = 'Wand of Qiraji Nobility';
            app.OffhandDropDown_2Label = uilabel(app.GearAndDPS);
            app.OffhandDropDown_2Label.Enable = 'off';
            app.OffhandDropDown_2Label.Text = 'Offhand';
            app.OffhandDropDown = uidropdown(app.GearAndDPS);
            app.OffhandDropDown.Items = {};
            app.OffhandDropDown.Enable = 'off';
            app.OffhandDropDown.Value = {};
            app.BackDropDown_2Label = uilabel(app.GearAndDPS);
            app.BackDropDown_2Label.Text = 'Back';
            app.BackDropDown = uidropdown(app.GearAndDPS);
            app.BackDropDown.Items = {'Cloak of the Devoured', 'Cloak of the Broodlord'};
            app.BackDropDown.ValueChangedFcn = createCallbackFcn(app, @BackDropDownValueChanged, true);
            app.BackDropDown.Value = 'Cloak of the Devoured';
            app.IntEditFieldLabel_4 = uilabel(app.GearAndDPS);
            app.IntEditFieldLabel_4.Text = 'Int';
            app.HeadInt = uieditfield(app.GearAndDPS, 'numeric');
            app.HeadInt.Value = 26;
            app.SPEditFieldLabel_4 = uilabel(app.GearAndDPS);
            app.SPEditFieldLabel_4.Text = 'SP';
            app.HeadSP = uieditfield(app.GearAndDPS, 'numeric');
            app.HeadSP.Value = 33;
            app.HitLabel_4 = uilabel(app.GearAndDPS);
            app.HitLabel_4.Text = 'Hit (%)';
            app.HeadHit = uieditfield(app.GearAndDPS, 'numeric');
            app.HeadHit.Value = 1;
            app.CritLabel_4 = uilabel(app.GearAndDPS);
            app.CritLabel_4.Text = 'Crit (%)';
            app.HeadCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.HeadCrit.Value = 1;
            app.IntEditFieldLabel_5 = uilabel(app.GearAndDPS);
            app.IntEditFieldLabel_5.Text = 'Int';
            app.HandsInt = uieditfield(app.GearAndDPS, 'numeric');
            app.HandsInt.Value = 26;
            app.SPEditFieldLabel_5 = uilabel(app.GearAndDPS);
            app.SPEditFieldLabel_5.Text = 'SP';
            app.HandsSP = uieditfield(app.GearAndDPS, 'numeric');
            app.HandsSP.Value = 33;
            app.HitLabel_5 = uilabel(app.GearAndDPS);
            app.HitLabel_5.Text = 'Hit (%)';
            app.HandsHit = uieditfield(app.GearAndDPS, 'numeric');
            app.HandsHit.Value = 1;
            app.CritLabel_5 = uilabel(app.GearAndDPS);
            app.CritLabel_5.Text = 'Crit (%)';
            app.HandsCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.DMTCheckBox = uicheckbox(app.GearAndDPS);
            app.DMTCheckBox.ValueChangedFcn = createCallbackFcn(app, @DMTCheckBoxValueChanged, true);
            app.DMTCheckBox.Text = 'DMT';
            app.OnyCheckBox = uicheckbox(app.GearAndDPS);
            app.OnyCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnyCheckBoxValueChanged, true);
            app.OnyCheckBox.Text = 'Ony';
            app.HakkarCheckBox = uicheckbox(app.GearAndDPS);
            app.HakkarCheckBox.ValueChangedFcn = createCallbackFcn(app, @HakkarCheckBoxValueChanged, true);
            app.HakkarCheckBox.Text = 'Hakkar';
            app.ShadowElixirCheckBox = uicheckbox(app.GearAndDPS);
            app.ShadowElixirCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShadowElixirCheckBoxValueChanged, true);
            app.ShadowElixirCheckBox.Text = 'Shadow Elixir';
            app.GreaterArcaneElixirCheckBox = uicheckbox(app.GearAndDPS);
            app.GreaterArcaneElixirCheckBox.ValueChangedFcn = createCallbackFcn(app, @GreaterArcaneElixirCheckBoxValueChanged, true);
            app.GreaterArcaneElixirCheckBox.Text = 'Greater Arcane Elixir';
            app.FlaskofSupremePowerCheckBox = uicheckbox(app.GearAndDPS);
            app.FlaskofSupremePowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlaskofSupremePowerCheckBoxValueChanged, true);
            app.FlaskofSupremePowerCheckBox.Text = 'Flask of Supreme Power';
            app.BrilliantWizardOilCheckBox = uicheckbox(app.GearAndDPS);
            app.BrilliantWizardOilCheckBox.ValueChangedFcn = createCallbackFcn(app, @BrilliantWizardOilCheckBoxValueChanged, true);
            app.BrilliantWizardOilCheckBox.Text = 'Brilliant Wizard Oil';
            app.Rank10ShadowboltCheckBox = uicheckbox(app.GearAndDPS);
            app.Rank10ShadowboltCheckBox.ValueChangedFcn = createCallbackFcn(app, @Rank10ShadowboltCheckBoxValueChanged, true);
            app.Rank10ShadowboltCheckBox.Text = 'Rank 10 Shadowbolt';
            app.MoonkinAuraCheckBox = uicheckbox(app.GearAndDPS);
            app.MoonkinAuraCheckBox.ValueChangedFcn = createCallbackFcn(app, @MoonkinAuraCheckBoxValueChanged, true);
            app.MoonkinAuraCheckBox.Text = 'Moonkin Aura';
            app.SongflowerCheckBox = uicheckbox(app.GearAndDPS);
            app.SongflowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @SongflowerCheckBoxValueChanged, true);
            app.SongflowerCheckBox.Text = 'Songflower';
            app.OptimizeButton = uibutton(app.GearAndDPS, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.Finger2DropDownLabel = uilabel(app.GearAndDPS);
            app.Finger2DropDownLabel.Text = 'Finger 2';
            app.Finger2DropDown = uidropdown(app.GearAndDPS);
            app.Finger2DropDown.Items = {'Band of Forced Concentration', 'Ring of the Fallen God', 'Ritssyn''s Ring of Chaos', 'Charm of the Shifting Sands', 'Ring of Unspoken Names', 'Ring of Spell Power', 'Band of Dark Dominion'};
            app.Finger2DropDown.ValueChangedFcn = createCallbackFcn(app, @Finger2DropDownValueChanged, true);
            app.Finger2DropDown.Value = 'Ring of Spell Power';
            app.OptimizeGauge = uigauge(app.GearAndDPS, 'linear');
            app.OptimizeGauge.Limits = [0 1];
            app.OptimizeGauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.OptimizeGauge.MajorTickLabels = {''};
            app.ManaPotionsCheckBox = uicheckbox(app.GearAndDPS);
            app.ManaPotionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManaPotionsCheckBoxValueChanged, true);
            app.ManaPotionsCheckBox.Text = 'Mana Potions';
            app.BlastedLandsIntCheckBox = uicheckbox(app.GearAndDPS);
            app.BlastedLandsIntCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlastedLandsIntCheckBoxValueChanged, true);
            app.BlastedLandsIntCheckBox.Text = 'Blasted Lands: Int';
            app.ElixiroftheSagesCheckBox = uicheckbox(app.GearAndDPS);
            app.ElixiroftheSagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ElixiroftheSagesCheckBoxValueChanged, true);
            app.ElixiroftheSagesCheckBox.Text = 'Elixir of the Sages';
            app.DemonicRunesCheckBox = uicheckbox(app.GearAndDPS);
            app.DemonicRunesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DemonicRunesCheckBoxValueChanged, true);
            app.DemonicRunesCheckBox.Text = 'Demonic Runes';
            app.ArcaneIntellectCheckBox = uicheckbox(app.GearAndDPS);
            app.ArcaneIntellectCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArcaneIntellectCheckBoxValueChanged, true);
            app.ArcaneIntellectCheckBox.Text = 'Arcane Intellect';
            app.AQ5setbonusCheckBox = uicheckbox(app.GearAndDPS);
            app.AQ5setbonusCheckBox.Enable = 'off';
            app.AQ5setbonusCheckBox.Text = 'AQ 5-set bonus';
            app.ManaTideCheckBox = uicheckbox(app.GearAndDPS);
            app.ManaTideCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManaTideCheckBoxValueChanged, true);
            app.ManaTideCheckBox.Text = 'Mana Tide';
            app.ManaSpringCheckBox = uicheckbox(app.GearAndDPS);
            app.ManaSpringCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManaSpringCheckBoxValueChanged, true);
            app.ManaSpringCheckBox.Text = 'Mana Spring';
            app.MagebloodCheckBox = uicheckbox(app.GearAndDPS);
            app.MagebloodCheckBox.ValueChangedFcn = createCallbackFcn(app, @MagebloodCheckBoxValueChanged, true);
            app.MagebloodCheckBox.Text = 'Mageblood';
            app.RendCheckBox = uicheckbox(app.GearAndDPS);
            app.RendCheckBox.ValueChangedFcn = createCallbackFcn(app, @RendCheckBoxValueChanged, true);
            app.RendCheckBox.Text = 'Rend';
            app.SelectAllBuffsConsumesCheckBox = uicheckbox(app.GearAndDPS);
            app.SelectAllBuffsConsumesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllBuffsConsumesCheckBoxValueChanged, true);
            app.SelectAllBuffsConsumesCheckBox.Text = 'Select All Buffs/Consumes';
            app.DMFCheckBox = uicheckbox(app.GearAndDPS);
            app.DMFCheckBox.ValueChangedFcn = createCallbackFcn(app, @DMFCheckBoxValueChanged, true);
            app.DMFCheckBox.Text = 'DMF';
            app.ShadowWeavingCheckBox = uicheckbox(app.GearAndDPS);
            app.ShadowWeavingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShadowWeavingCheckBoxValueChanged, true);
            app.ShadowWeavingCheckBox.Text = 'Shadow Weaving';
            app.EnchantedLabel = uilabel(app.GearAndDPS);
            app.EnchantedLabel.Text = 'Enchanted';
            app.HeadEnchant = uicheckbox(app.GearAndDPS);
            app.HeadEnchant.ValueChangedFcn = createCallbackFcn(app, @HeadEnchantValueChanged, true);
            app.HeadEnchant.Text = '';
            app.ShouldersEnchant = uicheckbox(app.GearAndDPS);
            app.ShouldersEnchant.ValueChangedFcn = createCallbackFcn(app, @ShouldersEnchantValueChanged, true);
            app.ShouldersEnchant.Text = '';
            app.ChestEnchant = uicheckbox(app.GearAndDPS);
            app.ChestEnchant.ValueChangedFcn = createCallbackFcn(app, @ChestEnchantValueChanged, true);
            app.ChestEnchant.Text = '';
            app.WeaponEnchant = uicheckbox(app.GearAndDPS);
            app.WeaponEnchant.ValueChangedFcn = createCallbackFcn(app, @WeaponEnchantValueChanged, true);
            app.WeaponEnchant.Text = '';
            app.WristsEnchant = uicheckbox(app.GearAndDPS);
            app.WristsEnchant.ValueChangedFcn = createCallbackFcn(app, @WristsEnchantValueChanged, true);
            app.WristsEnchant.Text = '';
            app.HandsEnchant = uicheckbox(app.GearAndDPS);
            app.HandsEnchant.ValueChangedFcn = createCallbackFcn(app, @HandsEnchantValueChanged, true);
            app.HandsEnchant.Text = '';
            app.LegsEnchant = uicheckbox(app.GearAndDPS);
            app.LegsEnchant.ValueChangedFcn = createCallbackFcn(app, @LegsEnchantValueChanged, true);
            app.LegsEnchant.Text = '';
            app.TubersCheckBox = uicheckbox(app.GearAndDPS);
            app.TubersCheckBox.ValueChangedFcn = createCallbackFcn(app, @TubersCheckBoxValueChanged, true);
            app.TubersCheckBox.Text = 'Tubers';
            app.CurseofShadowsCheckBox = uicheckbox(app.GearAndDPS);
            app.CurseofShadowsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CurseofShadowsCheckBoxValueChanged, true);
            app.CurseofShadowsCheckBox.Text = 'Curse of Shadows';
            app.CorruptionCheckBox = uicheckbox(app.GearAndDPS);
            app.CorruptionCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorruptionCheckBoxValueChanged, true);
            app.CorruptionCheckBox.Text = 'Corruption';
            app.ImmolateCheckBox = uicheckbox(app.GearAndDPS);
            app.ImmolateCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImmolateCheckBoxValueChanged, true);
            app.ImmolateCheckBox.Text = 'Immolate';
            app.CurseofAgonyCheckBox = uicheckbox(app.GearAndDPS);
            app.CurseofAgonyCheckBox.ValueChangedFcn = createCallbackFcn(app, @CurseofAgonyCheckBoxValueChanged, true);
            app.CurseofAgonyCheckBox.Text = 'Curse of Agony';
            app.CurseofDoomCheckBox = uicheckbox(app.GearAndDPS);
            app.CurseofDoomCheckBox.ValueChangedFcn = createCallbackFcn(app, @CurseofDoomCheckBoxValueChanged, true);
            app.CurseofDoomCheckBox.Text = 'Curse of Doom';
            app.ClipDoTsCheckBox = uicheckbox(app.GearAndDPS);
            app.ClipDoTsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClipDoTsCheckBoxValueChanged, true);
            app.ClipDoTsCheckBox.Text = 'Clip DoTs';
            app.MarkoftheWildCheckBox = uicheckbox(app.GearAndDPS);
            app.MarkoftheWildCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarkoftheWildCheckBoxValueChanged, true);
            app.MarkoftheWildCheckBox.Text = 'Mark of the Wild';
            app.FestivalSausageCheckBox = uicheckbox(app.GearAndDPS);
            app.FestivalSausageCheckBox.ValueChangedFcn = createCallbackFcn(app, @FestivalSausageCheckBoxValueChanged, true);
            app.FestivalSausageCheckBox.Text = 'Festival Sausage';
            app.MP5Label = uilabel(app.GearAndDPS);
            app.MP5Label.Text = 'MP5';
            app.ManaRestorationLabel = uilabel(app.GearAndDPS);
            app.ManaRestorationLabel.Text = 'Mana Restoration';
            app.IntLabel = uilabel(app.GearAndDPS);
            app.IntLabel.Text = 'Int';
            app.WorldBuffsLabel = uilabel(app.GearAndDPS);
            app.WorldBuffsLabel.Text = 'World Buffs';
            app.ConsumesLabel = uilabel(app.GearAndDPS);
            app.ConsumesLabel.Text = 'Consumes';
            app.DamageMultipliersLabel = uilabel(app.GearAndDPS);
            app.DamageMultipliersLabel.Text = 'Damage Multipliers';
            app.DisableOnetimeBuffsCheckBox = uicheckbox(app.GearAndDPS);
            app.DisableOnetimeBuffsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisableOnetimeBuffsCheckBoxValueChanged, true);
            app.DisableOnetimeBuffsCheckBox.Text = 'Disable One-time Buffs';
            app.SelectAllEnchantsCheckBox = uicheckbox(app.GearAndDPS);
            app.SelectAllEnchantsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllEnchantsCheckBoxValueChanged, true);
            app.SelectAllEnchantsCheckBox.Text = 'Select All Enchants';
            app.FoodLabel = uilabel(app.GearAndDPS);
            app.FoodLabel.Text = 'Food';
            app.Rank7Corruption = uicheckbox(app.GearAndDPS);
            app.Rank7Corruption.ValueChangedFcn = createCallbackFcn(app, @Rank7CorruptionValueChanged, true);
            app.Rank7Corruption.Text = 'Rank 7';
            app.Rank8Immolate = uicheckbox(app.GearAndDPS);
            app.Rank8Immolate.ValueChangedFcn = createCallbackFcn(app, @Rank8ImmolateValueChanged, true);
            app.Rank8Immolate.Text = 'Rank 8';
            app.SoulFireCheckBox = uicheckbox(app.GearAndDPS);
            app.SoulFireCheckBox.ValueChangedFcn = createCallbackFcn(app, @SoulFireCheckBoxValueChanged, true);
            app.SoulFireCheckBox.Text = 'Soul Fire';
            app.TalentsTab = uitab(app.TabGroup);
            app.TalentsTab.Title = 'Talents';
            app.TalentTreeDropDownLabel = uilabel(app.TalentsTab);
            app.TalentTreeDropDownLabel.Text = 'Talent Tree';
            app.TalentTreeDropDown = uidropdown(app.TalentsTab);
            app.TalentTreeDropDown.Items = {'Mana-efficient Destro Sac', 'Mana-inefficient Destro Sac', 'SM Sac', 'Mana-efficient SM Ruin', 'Mana-inefficient SM Ruin'};
            app.TalentTreeDropDown.ValueChangedFcn = createCallbackFcn(app, @TalentTreeDropDownValueChanged, true);
            app.TalentTreeDropDown.Value = 'Mana-efficient Destro Sac';
            app.CataclysmLabel = uilabel(app.TalentsTab);
            app.CataclysmLabel.Text = 'Cataclysm';
            app.CataclysmPoints = uieditfield(app.TalentsTab, 'numeric');
            app.CataclysmPoints.Editable = 'off';
            app.CataclysmPoints.Value = 5;
            app.CataclysmMax = uieditfield(app.TalentsTab, 'numeric');
            app.CataclysmMax.Editable = 'off';
            app.CataclysmMax.Value = 5;
            app.BaneLabel = uilabel(app.TalentsTab);
            app.BaneLabel.Text = 'Bane';
            app.BanePoints = uieditfield(app.TalentsTab, 'numeric');
            app.BanePoints.Editable = 'off';
            app.BanePoints.Value = 5;
            app.BaneMax = uieditfield(app.TalentsTab, 'numeric');
            app.BaneMax.Editable = 'off';
            app.BaneMax.Value = 5;
            app.DevastationLabel = uilabel(app.TalentsTab);
            app.DevastationLabel.Text = 'Devastation';
            app.DevastationPoints = uieditfield(app.TalentsTab, 'numeric');
            app.DevastationPoints.Editable = 'off';
            app.DevastationPoints.Value = 5;
            app.DevastationMax = uieditfield(app.TalentsTab, 'numeric');
            app.DevastationMax.Editable = 'off';
            app.DevastationMax.Value = 5;
            app.RuinLabel = uilabel(app.TalentsTab);
            app.RuinLabel.Text = 'Ruin';
            app.RuinPoints = uieditfield(app.TalentsTab, 'numeric');
            app.RuinPoints.Editable = 'off';
            app.RuinPoints.Value = 1;
            app.RuinMax = uieditfield(app.TalentsTab, 'numeric');
            app.RuinMax.Editable = 'off';
            app.RuinMax.Value = 1;
            app.SacLabel = uilabel(app.TalentsTab);
            app.SacLabel.Text = 'Sac';
            app.SacPoints = uieditfield(app.TalentsTab, 'numeric');
            app.SacPoints.Editable = 'off';
            app.SacPoints.Value = 1;
            app.SacMax = uieditfield(app.TalentsTab, 'numeric');
            app.SacMax.Editable = 'off';
            app.SacMax.Value = 1;
            app.ImprovedLifetapLabel = uilabel(app.TalentsTab);
            app.ImprovedLifetapLabel.Text = 'Improved Lifetap';
            app.ImprovedLifetapPoints = uieditfield(app.TalentsTab, 'numeric');
            app.ImprovedLifetapPoints.Editable = 'off';
            app.ImprovedLifetapPoints.Value = 2;
            app.ImprovedLifetapMax = uieditfield(app.TalentsTab, 'numeric');
            app.ImprovedLifetapMax.Editable = 'off';
            app.ImprovedLifetapMax.Value = 2;
            app.ShadowMasteryLabel = uilabel(app.TalentsTab);
            app.ShadowMasteryLabel.Text = 'Shadow Mastery';
            app.ShadowMasteryPoints = uieditfield(app.TalentsTab, 'numeric');
            app.ShadowMasteryPoints.Editable = 'off';
            app.ShadowMasteryMax = uieditfield(app.TalentsTab, 'numeric');
            app.ShadowMasteryMax.Editable = 'off';
            app.ShadowMasteryMax.Value = 5;
            app.ImprovedCorruptionLabel = uilabel(app.TalentsTab);
            app.ImprovedCorruptionLabel.Text = 'Improved Corruption';
            app.ImprovedCorruptionPoints = uieditfield(app.TalentsTab, 'numeric');
            app.ImprovedCorruptionPoints.Editable = 'off';
            app.ImprovedCorruptionPoints.Value = 5;
            app.ImprovedCorruptionMax = uieditfield(app.TalentsTab, 'numeric');
            app.ImprovedCorruptionMax.Editable = 'off';
            app.ImprovedCorruptionMax.Value = 5;
            app.OptimizeOverProbabilityTab = uitab(app.TabGroup);
            app.OptimizeOverProbabilityTab.Title = 'Optimize Over Probability';
            app.FullOptimizePlot = uiaxes(app.OptimizeOverProbabilityTab);
            title(app.FullOptimizePlot, '')
            xlabel(app.FullOptimizePlot, 'Probability of Achieving Higher DPS')
            ylabel(app.FullOptimizePlot, 'Optimal Damage')
            app.FullOptimizeButton = uibutton(app.OptimizeOverProbabilityTab, 'push');
            app.FullOptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @FullOptimizeButtonPushed, true);
            app.FullOptimizeButton.Text = 'Full Optimize';
            app.Label = uilabel(app.OptimizeOverProbabilityTab);
            app.Label.Text = 'Warning: This routine requires more extreme computational time the more points are requested!';
            app.MeshPointsSpinnerLabel = uilabel(app.OptimizeOverProbabilityTab);
            app.MeshPointsSpinnerLabel.Text = 'Mesh Points';
            app.MeshPointsSpinner = uispinner(app.OptimizeOverProbabilityTab);
            app.MeshPointsSpinner.Limits = [4 100];
            app.MeshPointsSpinner.RoundFractionalValues = 'on';
            app.MeshPointsSpinner.Value = 4;
            app.FullOptimizeGauge = uigauge(app.OptimizeOverProbabilityTab, 'linear');
            app.FullOptimizeGauge.Limits = [0 1];
            app.FullOptimizeGauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.FullOptimizeGauge.MajorTickLabels = {''};
            app.ToLabel = uilabel(app.OptimizeOverProbabilityTab);
            app.ToLabel.Text = 'To (%)';
            app.ToSpinner = uispinner(app.OptimizeOverProbabilityTab);
            app.ToSpinner.Step = 0.5;
            app.ToSpinner.Limits = [2.5 98.5];
            app.ToSpinner.ValueChangedFcn = createCallbackFcn(app, @ToSpinnerValueChanged, true);
            app.ToSpinner.Value = 98.5;
            app.FromLabel = uilabel(app.OptimizeOverProbabilityTab);
            app.FromLabel.Text = 'From (%)';
            app.FromSpinner = uispinner(app.OptimizeOverProbabilityTab);
            app.FromSpinner.Step = 0.5;
            app.FromSpinner.Limits = [2.5 98.5];
            app.FromSpinner.ValueChangedFcn = createCallbackFcn(app, @FromSpinnerValueChanged, true);
            app.FromSpinner.Value = 2.5;
            app.TotalsLabel = uilabel(app.UIFigure);
            app.TotalsLabel.Text = 'Totals';
            app.CritLabel_6 = uilabel(app.UIFigure);
            app.CritLabel_6.Text = 'Crit (%)';
            app.TotalCrit = uieditfield(app.UIFigure, 'numeric');
            app.TotalCrit.Editable = 'off';
            app.TotalCrit.Value = 7;
            app.HitLabel_6 = uilabel(app.UIFigure);
            app.HitLabel_6.Text = 'Hit (%)';
            app.TotalHit = uieditfield(app.UIFigure, 'numeric');
            app.TotalHit.Editable = 'off';
            app.TotalHit.Value = 10;
            app.SPEditFieldLabel_6 = uilabel(app.UIFigure);
            app.SPEditFieldLabel_6.Text = 'SP';
            app.TotalSP = uieditfield(app.UIFigure, 'numeric');
            app.TotalSP.Editable = 'off';
            app.TotalSP.Value = 495.7;
            app.IntEditFieldLabel_6 = uilabel(app.UIFigure);
            app.IntEditFieldLabel_6.Text = 'Int';
            app.TotalInt = uieditfield(app.UIFigure, 'numeric');
            app.TotalInt.Editable = 'off';
            app.TotalInt.Value = 206;
            app.TotalManaLabel_2 = uilabel(app.UIFigure);
            app.TotalManaLabel_2.Text = 'Total Mana';
            app.TotalMana = uieditfield(app.UIFigure, 'numeric');
            app.TotalMana.ValueDisplayFormat = '%11.6g';
            app.TotalMana.Editable = 'off';
            app.TotalMana.Value = 5803;
            app.MaxManaLabel = uilabel(app.UIFigure);
            app.MaxManaLabel.Text = 'Max Mana';
            app.NumberofEditFieldLabel_4 = uilabel(app.UIFigure);
            app.NumberofEditFieldLabel_4.Text = 'Number of ';
            app.NumberofShadowBoltCasts = uieditfield(app.UIFigure, 'numeric');
            app.NumberofShadowBoltCasts.Editable = 'off';
            app.NumberofShadowBoltCastsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofShadowBoltCastsEditFieldLabel.Text = 'Shadow Bolt Casts';
            app.EstimatedDPSEditFieldLabel_2 = uilabel(app.UIFigure);
            app.EstimatedDPSEditFieldLabel_2.Text = 'Estimated DPS';
            app.EstimatedDPSEditField = uieditfield(app.UIFigure, 'numeric');
            app.EstimatedDPSEditField.Editable = 'off';
            app.FightLengthsecondsEditField_2Label = uilabel(app.UIFigure);
            app.FightLengthsecondsEditField_2Label.Text = 'Fight Length (seconds)';
            app.FightLengthsecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FightLengthsecondsEditField.Value = 180;
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SaveDPSCheckBox = uicheckbox(app.UIFigure);
            app.SaveDPSCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveDPSCheckBoxValueChanged, true);
            app.SaveDPSCheckBox.Text = ' Save DPS';
            app.SavedDPSEditField = uieditfield(app.UIFigure, 'numeric');
            app.SavedDPSEditField.Editable = 'off';
            app.SavedDifference = uieditfield(app.UIFigure, 'numeric');
            app.SavedDifference.Editable = 'off';
            app.SavedRelativeDifference = uieditfield(app.UIFigure, 'text');
            app.SavedRelativeDifference.Value = '0';
            app.ExtremeLabel_3 = uilabel(app.UIFigure);
            app.ExtremeLabel_3.Text = 'Extreme';
            app.PreferenceSlider = uislider(app.UIFigure);
            app.PreferenceSlider.Limits = [0.025 0.985];
            app.PreferenceSlider.MajorTicks = [0.025 0.25 0.5 0.75 0.985];
            app.PreferenceSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.PreferenceSlider.ValueChangedFcn = createCallbackFcn(app, @PreferenceSliderValueChanged, true);
            app.PreferenceSlider.ValueChangingFcn = createCallbackFcn(app, @PreferenceSliderValueChanging, true);
            app.PreferenceSlider.Value = 0.5;
            app.ReliableLabel = uilabel(app.UIFigure);
            app.ReliableLabel.Text = 'Reliable';
            app.AverageLabel = uilabel(app.UIFigure);
            app.AverageLabel.Text = 'Average';
            app.NumberofLifeTapCasts = uieditfield(app.UIFigure, 'numeric');
            app.NumberofLifeTapCasts.Editable = 'off';
            app.NumberofShadowBoltCastsEditFieldLabel_2 = uilabel(app.UIFigure);
            app.NumberofShadowBoltCastsEditFieldLabel_2.Text = 'Life Tap Casts';
            app.NumberofEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofEditFieldLabel.Text = 'Number of ';
            app.MaxMana = uieditfield(app.UIFigure, 'numeric');
            app.MaxMana.ValueDisplayFormat = '%11.6g';
            app.MaxMana.Editable = 'off';
            app.MaxMana.Value = 5803;
            app.DPSPlot = uiaxes(app.UIFigure);
            title(app.DPSPlot, '')
            xlabel(app.DPSPlot, 'Probability of Achieving Higher DPS')
            ylabel(app.DPSPlot, 'DPS')
            app.RDPSPCheckBox = uicheckbox(app.UIFigure);
            app.RDPSPCheckBox.ValueChangedFcn = createCallbackFcn(app, @RDPSPCheckBoxValueChanged, true);
            app.RDPSPCheckBox.Text = 'RDPSP';
            app.DPSCheckBox = uicheckbox(app.UIFigure);
            app.DPSCheckBox.ValueChangedFcn = createCallbackFcn(app, @DPSCheckBoxValueChanged, true);
            app.DPSCheckBox.Text = 'DPS';
            app.DPSCheckBox.Value = true;
            app.NumberofDPSinRaidEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofDPSinRaidEditFieldLabel.Text = 'Number of DPS in Raid';
            app.NumberofDPSinRaidEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofDPSinRaidEditField.Value = 25;
            app.UsewiggleroomCheckBox = uicheckbox(app.UIFigure);
            app.UsewiggleroomCheckBox.Text = 'Use wiggle room';
            app.UsewiggleroomCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WarlockGearApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end