classdef WarlockGearApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        GearAndDPS                    matlab.ui.container.Tab
        NeckInt                       matlab.ui.control.NumericEditField
        NeckSP                        matlab.ui.control.NumericEditField
        NeckHit                       matlab.ui.control.NumericEditField
        NeckCrit                      matlab.ui.control.NumericEditField
        ShouldersInt                  matlab.ui.control.NumericEditField
        ShouldersSP                   matlab.ui.control.NumericEditField
        ShouldersHit                  matlab.ui.control.NumericEditField
        ShouldersCrit                 matlab.ui.control.NumericEditField
        BackInt                       matlab.ui.control.NumericEditField
        BackSP                        matlab.ui.control.NumericEditField
        BackHit                       matlab.ui.control.NumericEditField
        BackCrit                      matlab.ui.control.NumericEditField
        ChestInt                      matlab.ui.control.NumericEditField
        ChestSP                       matlab.ui.control.NumericEditField
        ChestHit                      matlab.ui.control.NumericEditField
        ChestCrit                     matlab.ui.control.NumericEditField
        WristInt                      matlab.ui.control.NumericEditField
        WristSP                       matlab.ui.control.NumericEditField
        WristHit                      matlab.ui.control.NumericEditField
        WristCrit                     matlab.ui.control.NumericEditField
        Ring1Int                      matlab.ui.control.NumericEditField
        Ring1SP                       matlab.ui.control.NumericEditField
        Ring1Hit                      matlab.ui.control.NumericEditField
        Ring1Crit                     matlab.ui.control.NumericEditField
        Ring2Int                      matlab.ui.control.NumericEditField
        Ring2SP                       matlab.ui.control.NumericEditField
        Ring2Hit                      matlab.ui.control.NumericEditField
        Ring2Crit                     matlab.ui.control.NumericEditField
        WaistInt                      matlab.ui.control.NumericEditField
        WaistSP                       matlab.ui.control.NumericEditField
        WaistHit                      matlab.ui.control.NumericEditField
        WaistCrit                     matlab.ui.control.NumericEditField
        LegsInt                       matlab.ui.control.NumericEditField
        LegsSP                        matlab.ui.control.NumericEditField
        LegsHit                       matlab.ui.control.NumericEditField
        LegsCrit                      matlab.ui.control.NumericEditField
        BootsInt                      matlab.ui.control.NumericEditField
        BootsSP                       matlab.ui.control.NumericEditField
        BootsHit                      matlab.ui.control.NumericEditField
        BootsCrit                     matlab.ui.control.NumericEditField
        Trinket1Int                   matlab.ui.control.NumericEditField
        Trinket1SP                    matlab.ui.control.NumericEditField
        Trinket1Hit                   matlab.ui.control.NumericEditField
        Trinket1Crit                  matlab.ui.control.NumericEditField
        Trinket2Int                   matlab.ui.control.NumericEditField
        Trinket2SP                    matlab.ui.control.NumericEditField
        Trinket2Hit                   matlab.ui.control.NumericEditField
        Trinket2Crit                  matlab.ui.control.NumericEditField
        WandInt                       matlab.ui.control.NumericEditField
        WandSP                        matlab.ui.control.NumericEditField
        WandHit                       matlab.ui.control.NumericEditField
        WandCrit                      matlab.ui.control.NumericEditField
        WeaponInt                     matlab.ui.control.NumericEditField
        WeaponSP                      matlab.ui.control.NumericEditField
        WeaponHit                     matlab.ui.control.NumericEditField
        WeaponCrit                    matlab.ui.control.NumericEditField
        OffhandInt                    matlab.ui.control.NumericEditField
        OffhandSP                     matlab.ui.control.NumericEditField
        OffhandHit                    matlab.ui.control.NumericEditField
        OffhandCrit                   matlab.ui.control.NumericEditField
        ReliableLabel                 matlab.ui.control.Label
        AverageLabel                  matlab.ui.control.Label
        ProbabilityofDoingMoreLabel   matlab.ui.control.Label
        TotalsLabel                   matlab.ui.control.Label
        ThantheEstimatedDPSLabel      matlab.ui.control.Label
        NumberofShadowBoltCastsEditFieldLabel  matlab.ui.control.Label
        NumberofEditFieldLabel        matlab.ui.control.Label
        NumberofLifeTapCasts          matlab.ui.control.NumericEditField
        NumberofShadowBoltCastsEditFieldLabel_2  matlab.ui.control.Label
        HeadDropDown_2Label           matlab.ui.control.Label
        HeadDropDown                  matlab.ui.control.DropDown
        NeckDropDown_2Label           matlab.ui.control.Label
        NeckDropDown                  matlab.ui.control.DropDown
        ShouldersDropDown_2Label      matlab.ui.control.Label
        ShouldersDropDown             matlab.ui.control.DropDown
        ChestDropDown_2Label          matlab.ui.control.Label
        ChestDropDown                 matlab.ui.control.DropDown
        WristDropDown_2Label          matlab.ui.control.Label
        WristDropDown                 matlab.ui.control.DropDown
        HandsDropDown_2Label          matlab.ui.control.Label
        HandsDropDown                 matlab.ui.control.DropDown
        WaistDropDown_2Label          matlab.ui.control.Label
        WaistDropDown                 matlab.ui.control.DropDown
        LegsDropDown_2Label           matlab.ui.control.Label
        LegsDropDown                  matlab.ui.control.DropDown
        FeetDropDownLabel             matlab.ui.control.Label
        FeetDropDown                  matlab.ui.control.DropDown
        Finger1DropDownLabel          matlab.ui.control.Label
        Finger1DropDown               matlab.ui.control.DropDown
        Finger2DropDownLabel          matlab.ui.control.Label
        Finger2DropDown               matlab.ui.control.DropDown
        Trinket1DropDown_2Label       matlab.ui.control.Label
        Trinket1DropDown              matlab.ui.control.DropDown
        Trinket2DropDown_2Label       matlab.ui.control.Label
        Trinket2DropDown              matlab.ui.control.DropDown
        WeaponDropDown_2Label         matlab.ui.control.Label
        WeaponDropDown                matlab.ui.control.DropDown
        WandDropDown_2Label           matlab.ui.control.Label
        WandDropDown                  matlab.ui.control.DropDown
        OffhandDropDown_2Label        matlab.ui.control.Label
        OffhandDropDown               matlab.ui.control.DropDown
        BackDropDown_2Label           matlab.ui.control.Label
        BackDropDown                  matlab.ui.control.DropDown
        IntEditFieldLabel_4           matlab.ui.control.Label
        HeadInt                       matlab.ui.control.NumericEditField
        SPEditFieldLabel_4            matlab.ui.control.Label
        HeadSP                        matlab.ui.control.NumericEditField
        HitLabel_4                    matlab.ui.control.Label
        HeadHit                       matlab.ui.control.NumericEditField
        CritLabel_4                   matlab.ui.control.Label
        HeadCrit                      matlab.ui.control.NumericEditField
        FightLengthsecondsEditField_2Label  matlab.ui.control.Label
        FightLengthsecondsEditField   matlab.ui.control.NumericEditField
        IntEditFieldLabel_5           matlab.ui.control.Label
        HandsInt                      matlab.ui.control.NumericEditField
        SPEditFieldLabel_5            matlab.ui.control.Label
        HandsSP                       matlab.ui.control.NumericEditField
        HitLabel_5                    matlab.ui.control.Label
        HandsHit                      matlab.ui.control.NumericEditField
        CritLabel_5                   matlab.ui.control.Label
        HandsCrit                     matlab.ui.control.NumericEditField
        ExtremeLabel_3                matlab.ui.control.Label
        PreferenceSlider              matlab.ui.control.Slider
        IntEditFieldLabel_6           matlab.ui.control.Label
        TotalInt                      matlab.ui.control.NumericEditField
        SPEditFieldLabel_6            matlab.ui.control.Label
        TotalSP                       matlab.ui.control.NumericEditField
        HitLabel_6                    matlab.ui.control.Label
        TotalHit                      matlab.ui.control.NumericEditField
        CritLabel_6                   matlab.ui.control.Label
        TotalCrit                     matlab.ui.control.NumericEditField
        ManaLabel_2                   matlab.ui.control.Label
        TotalMana                     matlab.ui.control.NumericEditField
        NumberofEditFieldLabel_4      matlab.ui.control.Label
        NumberofShadowBoltCasts       matlab.ui.control.NumericEditField
        CalculateButton               matlab.ui.control.Button
        EstimatedDPSEditFieldLabel_2  matlab.ui.control.Label
        EstimatedDPSEditField         matlab.ui.control.NumericEditField
        SaveDPSCheckBox               matlab.ui.control.CheckBox
        SavedDPSEditField             matlab.ui.control.NumericEditField
        SavedDifference               matlab.ui.control.NumericEditField
        DMTCheckBox                   matlab.ui.control.CheckBox
        OnyCheckBox                   matlab.ui.control.CheckBox
        HakkarCheckBox                matlab.ui.control.CheckBox
        ShadowElixirCheckBox          matlab.ui.control.CheckBox
        GreaterArcaneElixirCheckBox   matlab.ui.control.CheckBox
        FlaskofSupremePowerCheckBox   matlab.ui.control.CheckBox
        BrilliantWizardOilCheckBox    matlab.ui.control.CheckBox
        Rank10ShadowboltCheckBox      matlab.ui.control.CheckBox
        MoonkinAuraCheckBox           matlab.ui.control.CheckBox
        SongflowerCheckBox            matlab.ui.control.CheckBox
        SavedRelativeDifference       matlab.ui.control.NumericEditField
        OptimizeButton                matlab.ui.control.Button
        TalentsTab                    matlab.ui.container.Tab
        TalentModificationsComingSoonLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Slot = string(zeros(34,1));
        Name = string(zeros(34,1));
        Int = zeros(34,1);
        SP = zeros(34,1);
        Hit = zeros(34,1);
        Crit = zeros(34,1);
    end
    
    methods (Access = private)
        
        % ToDo: Really need to double check the defaults of 108 and 3.48
        % and 2713. It also might be 60.6 on the int-crit conversion
        function UpdateTotals(app)
            app.TotalInt.Value = app.HeadInt.Value + app.NeckInt.Value + app.ShouldersInt.Value + app.BackInt.Value + app.ChestInt.Value + ...
                                 app.WristInt.Value + app.HandsInt.Value + app.WaistInt.Value + app.LegsInt.Value + app.FeetInt.Value + ...
                                 app.Ring1Int.Value + app.Ring2Int.Value + app.Trinket1Int.Value + app.Trinket2Int.Value + app.WeaponInt.Value + ...
                                 app.OffhandInt.Value + app.WandInt.Value + 108; % default of 108
                             
            app.TotalSP.Value = app.HeadSP.Value + app.NeckSP.Value + app.ShouldersSP.Value + app.BackSP.Value + app.ChestSP.Value + ...
                                app.WristSP.Value + app.HandsSP.Value + app.WaistSP.Value + app.LegsSP.Value + app.FeetSP.Value + ...
                                app.Ring1SP.Value + app.Ring2SP.Value + app.Trinket1SP.Value + app.Trinket2SP.Value + app.WeaponSP.Value + ...
                                app.OffhandSP.Value + app.WandSP.Value;
            
            app.TotalHit.Value = app.HeadHit.Value + app.NeckHit.Value + app.ShouldersHit.Value + app.BackHit.Value + app.ChestHit.Value + ...
                                 app.WristHit.Value + app.HandsHit.Value + app.WaistHit.Value + app.LegsHit.Value + app.FeetHit.Value + ...
                                 app.Ring1Hit.Value + app.Ring2Hit.Value + app.Trinket1Hit.Value + app.Trinket2Hit.Value + app.WeaponHit.Value + ...
                                 app.OffhandHit.Value + app.WandHit.Value;
                             
            app.TotalCrit.Value = app.HeadCrit.Value + app.NeckCrit.Value + app.ShouldersCrit.Value + app.BackCrit.Value + app.ChestCrit.Value + ...
                                  app.WristCrit.Value + app.HandsCrit.Value + app.WaistCrit.Value + app.LegsCrit.Value + app.FeetCrit.Value + ...
                                  app.Ring1Crit.Value + app.Ring2Crit.Value + app.Trinket1Crit.Value + app.Trinket2Crit.Value + app.WeaponCrit.Value + ...
                                  app.OffhandCrit.Value + app.WandCrit.Value + 3.48; % + 3.48 baseline
                              
            if (app.DMTCheckBox.Value)
                app.TotalCrit.Value = app.TotalCrit.Value + 3;
            end
                              
            if (app.OnyCheckBox.Value)
                app.TotalCrit.Value = app.TotalCrit.Value + 10;
            end
                              
            if (app.HakkarCheckBox.Value)
                app.TotalInt.Value = app.TotalInt.Value * 1.15;
            end
            
            if (app.MoonkinAuraCheckBox.Value)
                app.TotalCrit.Value = app.TotalCrit.Value + 3;
            end
            
            if (app.SongflowerCheckBox.Value)
                app.TotalCrit.Value = app.TotalCrit.Value + 5;
            end
            
            if (app.ShadowElixirCheckBox.Value)
                app.TotalSP.Value = app.TotalSP.Value + 40;
            end
                              
            if (app.GreaterArcaneElixirCheckBox.Value)
                app.TotalSP.Value = app.TotalSP.Value + 35;
            end
                              
            if (app.FlaskofSupremePowerCheckBox.Value)
                app.TotalSP.Value = app.TotalSP.Value + 150;
            end
            
            if (app.BrilliantWizardOilCheckBox.Value)
                app.TotalSP.Value = app.TotalSP.Value + 36;
                app.TotalCrit.Value = app.TotalCrit.Value + 1;
            end
                              
            app.TotalCrit.Value = app.TotalCrit.Value + app.TotalInt.Value/60;
                              
            app.TotalMana.Value = app.TotalInt.Value * 15 + 2713; % + 2713 baseline
        end
        
        function CalculateDPS(app)
            s = app.TotalSP.Value;
            p = min(0.83 + app.TotalHit.Value/100,0.99);
            q = app.TotalCrit.Value/100;
            
            c = app.PreferenceSlider.Value;
            
            switch c
                case 0
                    c = 0.05;
                case 1
                    c = 0.95;
            end
            
            if (app.Rank10ShadowboltCheckBox.Value)
                SBoltCost = 380;
                BaseSBoltDmg = 510;
            else
                SBoltCost = 370;
                BaseSBoltDmg = 481;
            end
            
            BaseLifeTap = 424;
            T = app.FightLengthsecondsEditField.Value;
            LifeTapAmount = (BaseLifeTap + 1.5/3.5 * app.TotalSP.Value);
            Mseq = app.TotalMana.Value;
            Nseq = min([floor(Mseq/SBoltCost) floor(T/2.5)]);
            eps = T - Nseq*2.5; j = 2;
            NumberOfLifeTaps = 0;
            while (eps >= 2.5)
                Mseq(j) = Mseq(j-1)/SBoltCost - floor(Mseq(j-1)/SBoltCost) + LifeTapAmount;
                Nseq(j) = floor(Mseq(j)/SBoltCost);
                eps = eps - 1.5; NumberOfLifeTaps = NumberOfLifeTaps + 1;
                k = Nseq(j);
                while (k > 0 && eps >= 2.5)
                    eps = eps - 2.5;
                    k = k - 1;
                end
                j = j + 1;
            end
            N = sum(Nseq);
            app.NumberofShadowBoltCasts.Value = N;
            app.NumberofLifeTapCasts.Value = NumberOfLifeTaps;
            
            % ERROR: Need to fix the manual calculation of 962 and 6/3.5 to
            % account for rank 10 option
            mu = p * (BaseSBoltDmg + 3/3.5 * s) + 962 * p * q + 6/3.5 * p * q * s;
            sigma = sqrt( (52^2/12 + (BaseSBoltDmg + 3/3.5 * s)^2) * p * (4 * q * (1 - q) + (2 * q + 1)^2) - (BaseSBoltDmg + 3/3.5 * s)^2 * p^2 * (2 * q + 1)^2 );
            
            app.EstimatedDPSEditField.Value = (sqrt(2) * erfinv(2*(1-c)-1) * sqrt(N)*sigma + N*mu) / app.FightLengthsecondsEditField.Value;
            
            if (app.SaveDPSCheckBox.Value)
                app.SavedDifference.Value = app.EstimatedDPSEditField.Value - app.SavedDPSEditField.Value;
                
                if (app.SavedDifference.Value == 0)
                    app.SavedDifference.FontColor = [0 0 0];
                end
                
                if (app.SavedDifference.Value > 0)
                    app.SavedDifference.FontColor = [0.47,0.67,0.19];
                end
                
                if (app.SavedDifference.Value < 0)
                    app.SavedDifference.FontColor = [0.64,0.08,0.18];
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Slot(1) = "Head";
            app.Slot(2) = "Head";
            app.Slot(3) = "Head";
            app.Slot(4) = "Neck";
            app.Slot(5) = "Neck";
            app.Slot(6) = "Neck";
            app.Slot(7) = "Shoulders";
            app.Slot(8) = "Shoulders";
            app.Slot(9) = "Back";
            app.Slot(10) = "Back";
            app.Slot(11) = "Chest";
            app.Slot(12) = "Chest";
            app.Slot(13) = "Chest";
            app.Slot(14) = "Wrists";
            app.Slot(15) = "Wrists";
            app.Slot(16) = "Hands";
            app.Slot(17) = "Hands";
            app.Slot(18) = "Hands";
            app.Slot(19) = "Waist";
            app.Slot(20) = "Waist";
            app.Slot(21) = "Legs";
            app.Slot(22) = "Feet";
            app.Slot(23) = "Feet";
            app.Slot(24) = "Finger";
            app.Slot(25) = "Finger";
            app.Slot(26) = "Finger";
            app.Slot(27) = "Finger";
            app.Slot(28) = "Finger";
            app.Slot(29) = "Finger";
            app.Slot(30) = "Trinket";
            app.Slot(31) = "Trinket";
            app.Slot(32) = "Weapon";
            app.Slot(33) = "Weapon";
            app.Slot(34) = "Wand";
            
            app.Name(1) = "Doomcaller's Cirlet";
            app.Name(2) = "Mish'undare";
            app.Name(3) = "Hexer's Cover";
            app.Name(4) = "Soul Corrupter's Necklace";
            app.Name(5) = "Amulet of Vek'nilash";
            app.Name(6) = "Choker of the Fire Lord";
            app.Name(7) = "Doomcaller's Mantle";
            app.Name(8) = "Mantle of the Blackwing Cabal";
            app.Name(9) = "Cloak of the Devoured";
            app.Name(10) = "Cloak of the Broodlord";
            app.Name(11) = "Robe of Volatile Power";
            app.Name(12) = "Garb of Royal Ascension";
            app.Name(13) = "Doomcaller's Robes";
            app.Name(14) = "Burrower Wrists";
            app.Name(15) = "Wrists of Arcane Accuracy";
            app.Name(16) = "Dark Storm Gauntlets";
            app.Name(17) = "Nemesis Hands";
            app.Name(18) = "Ebony Flame Hands";
            app.Name(19) = "Eyestalk Waistcord";
            app.Name(20) = "Angelista's Grasp";
            app.Name(21) = "Leggings of the Black Blizzard";
            app.Name(22) = "Feet of Epiphany";
            app.Name(23) = "Bloodvine Feet";
            app.Name(24) = "Charm of the Shifting Sands";
            app.Name(25) = "Ritssyn's Ring of Chaos";
            app.Name(26) = "Ring of Unspoken Names";
            app.Name(27) = "Band of Forced Concentration";
            app.Name(28) = "Band of Dark Dominion";
            app.Name(29) = "Ring of Spell Power";
            app.Name(30) = "Talisman of Ephemeral Power";
            app.Name(31) = "Neltharion's Tear";
            app.Name(32) = "Staff of the Shadowflame";
            app.Name(33) = "Blessed Qiraji Acolyte Staff";
            app.Name(34) = "Wand of Qiraji Nobility";
            
            app.Int(1) = 26; app.SP(1) = 33; app.Hit(1) = 1; app.Crit(1) = 1;
            app.Int(2) = 24; app.SP(2) = 35; app.Hit(2) = 0; app.Crit(2) = 2;
            app.Int(3) = 10; app.SP(3) = 41; app.Hit(3) = 0; app.Crit(3) = 0;
            app.Int(4) = 16; app.SP(4) = 0; app.Hit(4) = 1; app.Crit(4) = 0;
            app.Int(5) = 5; app.SP(5) = 27; app.Hit(5) = 0; app.Crit(5) = 1;
            app.Int(6) = 7; app.SP(6) = 34; app.Hit(6) = 0; app.Crit(6) = 0;
            app.Int(7) = 11; app.SP(7) = 28; app.Hit(7) = 1; app.Crit(7) = 0;
            app.Int(8) = 16; app.SP(8) = 34; app.Hit(8) = 0; app.Crit(8) = 0;
            app.Int(9) = 10; app.SP(9) = 30; app.Hit(9) = 1; app.Crit(9) = 0;
            app.Int(10) = 14; app.SP(10) = 28; app.Hit(10) = 0; app.Crit(10) = 0;
            app.Int(11) = 15; app.SP(11) = 23; app.Hit(11) = 0; app.Crit(11) = 2;
            app.Int(12) = 15; app.SP(12) = 23; app.Hit(12) = 2; app.Crit(12) = 0;
            app.Int(13) = 17; app.SP(13) = 41; app.Hit(13) = 0; app.Crit(13) = 1;
            app.Int(14) = 13; app.SP(14) = 28; app.Hit(14) = 0; app.Crit(14) = 0;
            app.Int(15) = 12; app.SP(15) = 21; app.Hit(15) = 1; app.Crit(15) = 0;
            app.Int(16) = 15; app.SP(16) = 37; app.Hit(16) = 1; app.Crit(16) = 0;
            app.Int(17) = 15; app.SP(17) = 15; app.Hit(17) = 0; app.Crit(17) = 1;
            app.Int(18) = 12; app.SP(18) = 43; app.Hit(18) = 0; app.Crit(18) = 0;
            app.Int(19) = 9; app.SP(19) = 41; app.Hit(19) = 0; app.Crit(19) = 1;
            app.Int(20) = 20; app.SP(20) = 0; app.Hit(20) = 2; app.Crit(20) = 0;
            app.Int(21) = 16; app.SP(21) = 41; app.Hit(21) = 0; app.Crit(21) = 1;
            app.Int(22) = 19; app.SP(22) = 34; app.Hit(22) = 0; app.Crit(22) = 0;
            app.Int(23) = 16; app.SP(23) = 19; app.Hit(23) = 1; app.Crit(23) = 0;
            app.Int(24) = 12; app.SP(24) = 25; app.Hit(24) = 0; app.Crit(24) = 0;
            app.Int(25) = 0; app.SP(25) = 25; app.Hit(25) = 0; app.Crit(25) = 1;
            app.Int(26) = 0; app.SP(26) = 14; app.Hit(26) = 1; app.Crit(26) = 1;
            app.Int(27) = 12; app.SP(27) = 21; app.Hit(27) = 1; app.Crit(27) = 0;
            app.Int(28) = 6; app.SP(28) = 33; app.Hit(28) = 0; app.Crit(28) = 0;
            app.Int(29) = 0; app.SP(29) = 33; app.Hit(29) = 0; app.Crit(29) = 0;
            app.Int(30) = 0; app.SP(30) = 176*6/90; app.Hit(30) = 0; app.Crit(30) = 0;
            app.Int(31) = 0; app.SP(31) = 44; app.Hit(31) = 2; app.Crit(31) = 0;
            app.Int(32) = 29; app.SP(32) = 84; app.Hit(32) = 0; app.Crit(32) = 2;
            app.Int(33) = 33; app.SP(33) = 76; app.Hit(33) = 2; app.Crit(33) = 1;
            app.Int(34) = 0; app.SP(34) = 19; app.Hit(34) = 0; app.Crit(34) = 0;
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            IDs.Head = find(strcmp(app.Slot,"Head")); Nums.Head = numel(IDs.Head);
            IDs.Neck = find(strcmp(app.Slot,"Neck")); Nums.Neck = numel(IDs.Neck);
            IDs.Shoulders = find(strcmp(app.Slot,"Shoulders")); Nums.Shoulders = numel(IDs.Shoulders);
            IDs.Back = find(strcmp(app.Slot,"Back")); Nums.Back = numel(IDs.Back);
            IDs.Chest = find(strcmp(app.Slot,"Chest")); Nums.Chest = numel(IDs.Chest);
            IDs.Wrists = find(strcmp(app.Slot,"Wrists")); Nums.Wrists = numel(IDs.Wrists);
            
            IDs.Hands = find(strcmp(app.Slot,"Hands")); Nums.Hands = numel(IDs.Hands);
            IDs.Waist = find(strcmp(app.Slot,"Waist")); Nums.Waist = numel(IDs.Waist);
            IDs.Legs = find(strcmp(app.Slot,"Legs")); Nums.Legs = numel(IDs.Legs);
            IDs.Feet = find(strcmp(app.Slot,"Feet")); Nums.Feet = numel(IDs.Feet);
            IDs.Finger = find(strcmp(app.Slot,"Finger")); Nums.Finger = numel(IDs.Finger);
            
            IDs.Weapon = find(strcmp(app.Slot,"Weapon")); Nums.Weapon = numel(IDs.Weapon);
            IDs.Wand = find(strcmp(app.Slot,"Wand")); Nums.Wand = numel(IDs.Wand);
            
            nSlots = numel(fields(IDs)) + 1;
            ArrayInds = ones(nSlots,1);
            
            temp = table2array(struct2table(Nums));
            MaxCombos = prod(temp(1:10)) * nchoosek(temp(11),2) * prod(temp(12:end));
            
            Combo = cell(MaxCombos,1);
            ComboInt = zeros(MaxCombos,1);
            ComboSP = zeros(MaxCombos,1);
            ComboCrit = zeros(MaxCombos,1);
            ComboHit = zeros(MaxCombos,1);
            ComboDPS = zeros(MaxCombos,1);
            
            counter = 1; % total counter
            for i1=1:Nums.Head
            for i2=1:Nums.Neck
            for i3=1:Nums.Shoulders
            for i4=1:Nums.Back
            for i5=1:Nums.Chest
            for i6=1:Nums.Wrists
            for i7=1:Nums.Hands
            for i8=1:Nums.Waist
            for i9=1:Nums.Legs
            for i10=1:Nums.Feet
            for i11=1:Nums.Finger
            for i12=1:Nums.Finger
            for i13=1:Nums.Weapon
            for i14=1:Nums.Wand
                
            ArrayInds(1) = i1;
            ArrayInds(2) = i2;
            ArrayInds(3) = i3;
            ArrayInds(4) = i4;
            ArrayInds(5) = i5;
            ArrayInds(6) = i6;
            ArrayInds(7) = i7;
            ArrayInds(8) = i8;
            ArrayInds(9) = i9;
            ArrayInds(10) = i10;
            ArrayInds(11) = i11; % Ring 1
            ArrayInds(12) = i12; % Ring 2
            ArrayInds(13) = i13;
            ArrayInds(14) = i14;
            
                if (i12 > i11)
                    
                Combo{counter} = ArrayInds;
                
                app.HeadInt.Value = app.Int(IDs.Head(ArrayInds(1))); app.HeadSP.Value = app.SP(IDs.Head(ArrayInds(1))); app.HeadHit.Value = app.Hit(IDs.Head(ArrayInds(1))); app.HeadCrit.Value = app.Crit(IDs.Head(ArrayInds(1)));
            
                UpdateTotals(app)
                CalculateDPS(app)
                
                ComboInt(counter) = app.TotalInt.Value;
                ComboSP(counter) = app.TotalSP.Value;
                ComboHit(counter) = app.TotalHit.Value;
                ComboCrit(counter) = app.TotalCrit.Value;
                
                ComboDPS(counter) = app.EstimatedDPSEditField.Value;
                
%                 ComboInt(counter) = sum(app.Int([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
%                     IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
%                     IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Ring(ArrayInds(11)) IDs.Ring(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
%                     IDs.Wand(ArrayInds(14))]));
%                 
%                 ComboSP(counter) = sum(app.Sp([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
%                     IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
%                     IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Ring(ArrayInds(11)) IDs.Ring(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
%                     IDs.Wand(ArrayInds(14))]));
%                 
%                 ComboCrit(counter) = round(sum(app.Hit([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
%                     IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
%                     IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Ring(ArrayInds(11)) IDs.Ring(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
%                     IDs.Wand(ArrayInds(14))])),2);
%                 
%                 ComboHit(counter) = round(sum(app.Hit([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
%                     IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
%                     IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Ring(ArrayInds(11)) IDs.Ring(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
%                     IDs.Wand(ArrayInds(14))])),2);
            
                counter = counter + 1;
                
                end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            UpdateTotals(app)
            CalculateDPS(app)
        end

        % Value changed function: SaveDPSCheckBox
        function SaveDPSCheckBoxValueChanged(app, event)
            if (app.SaveDPSCheckBox.Value)
                app.SavedDPSEditField.Value = app.EstimatedDPSEditField.Value;
                app.SavedDifference.Value = app.EstimatedDPSEditField.Value - app.SavedDPSEditField.Value;
                
                if (app.SavedDifference.Value == 0)
                    app.SavedDifference.FontColor = [0 0 0];
                end
                
                if (app.SavedDifference.Value > 0)
                    app.SavedDifference.FontColor = [0.47,0.67,0.19];
                end
                
                if (app.SavedDifference.Value < 0)
                    app.SavedDifference.FontColor = [0.64,0.08,0.18];
                end
                
                app.SavedRelativeDifference.Value = app.EstimatedDPSEditField.Value / app.SavedDPSEditField.Value;
                
                if (app.SavedDifference.Value == 0)
                    app.SavedRelativeDifference.FontColor = [0 0 0];
                end
                
                if (app.SavedDifference.Value > 0)
                    app.SavedRelativeDifference.FontColor = [0.47,0.67,0.19];
                end
                
                if (app.SavedDifference.Value < 0)
                    app.SavedRelativeDifference.FontColor = [0.64,0.08,0.18];
                end
            else
                app.SavedDPSEditField.Value = 0;
                app.SavedDifference.Value = 0;
                app.SavedRelativeDifference.Value = 0;
                app.SavedDifference.FontColor = [0 0 0];
                app.SavedRelativeDifference.FontColor = [0 0 0];
            end
        end

        % Value changed function: HeadDropDown
        function HeadDropDownValueChanged(app, event)
            value = app.HeadDropDown.Value;
            
            switch value
                case "Doomcaller's Circlet"
                    app.HeadInt.Value = 26;
                    app.HeadSP.Value = 33;
                    app.HeadHit.Value = 1;
                    app.HeadCrit.Value = 1;
                case "Mish'undare"
                    app.HeadInt.Value = 24;
                    app.HeadSP.Value = 35;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 2;
                case "Hexer's Cover"
                    app.HeadInt.Value = 10;
                    app.HeadSP.Value = 41;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 0;
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: NeckDropDown
        function NeckDropDownValueChanged(app, event)
            value = app.NeckDropDown.Value;
            
            switch value
                case "Soul Corrupter's Necklace"
                    app.HeadInt.Value = 10;
                    app.HeadSP.Value = 0;
                    app.HeadHit.Value = 1;
                    app.HeadCrit.Value = 0;
                case "Amulet of Vek'nilash"
                    app.HeadInt.Value = 5;
                    app.HeadSP.Value = 27;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 1;
                case "Choker of the Firelord"
                    app.HeadInt.Value = 7;
                    app.HeadSP.Value = 34;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 0;
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: ShouldersDropDown
        function ShouldersDropDownValueChanged(app, event)
            value = app.ShouldersDropDown.Value;
            
            switch value
                case "Doomcaller's Mantle"
                    app.HeadInt.Value = 11;
                    app.HeadSP.Value = 28;
                    app.HeadHit.Value = 1;
                    app.HeadCrit.Value = 0;
                case "Mantle of the Blackwing Cabal"
                    app.HeadInt.Value = 16;
                    app.HeadSP.Value = 34;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 0;
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: BackDropDown
        function BackDropDownValueChanged(app, event)
            value = app.BackDropDown.Value;
            
            switch value
                case "Cloak of the Devoured"
                    app.HeadInt.Value = 10;
                    app.HeadSP.Value = 30;
                    app.HeadHit.Value = 1;
                    app.HeadCrit.Value = 0;
                case "Cloak of the Broodlord"
                    app.HeadInt.Value = 14;
                    app.HeadSP.Value = 28;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 0;
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: ChestDropDown
        function ChestDropDownValueChanged(app, event)
            value = app.ChestDropDown.Value;
            
            switch value
                case "Robe of Volatile Power"
                    app.HeadInt.Value = 15;
                    app.HeadSP.Value = 23;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 2;
                case "Garb of Royal Ascension"
                    app.HeadInt.Value = 15;
                    app.HeadSP.Value = 23;
                    app.HeadHit.Value = 2;
                    app.HeadCrit.Value = 2;
                case "Doomcaller's Robes"
                    app.HeadInt.Value = 17;
                    app.HeadSP.Value = 41;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 1;
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: WristDropDown
        function WristDropDownValueChanged(app, event)
            value = app.WristDropDown.Value;
            
            switch value
                case "Burrower Wrists"
                    app.HeadInt.Value = 13;
                    app.HeadSP.Value = 28;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 0;
                case "Wrists of Arcane Accuracy"
                    app.HeadInt.Value = 12;
                    app.HeadSP.Value = 21;
                    app.HeadHit.Value = 1;
                    app.HeadCrit.Value = 0;
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: Finger1DropDown
        function Finger1DropDownValueChanged(app, event)
            value = app.Finger1DropDown.Value;
            
            switch value
                case "Doomcaller's Circlet"
                    app.HeadInt.Value = 26;
                    app.HeadSP.Value = 33;
                    app.HeadHit.Value = 1;
                    app.HeadCrit.Value = 1;
                case "Mish'undare"
                    app.HeadInt.Value = 24;
                    app.HeadSP.Value = 35;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 2;
                case "Hexer's Cover"
                    app.HeadInt.Value = 10;
                    app.HeadSP.Value = 41;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 0;
                case "Doomcaller's Circlet"
                    app.HeadInt.Value = 26;
                    app.HeadSP.Value = 33;
                    app.HeadHit.Value = 1;
                    app.HeadCrit.Value = 1;
                case "Mish'undare"
                    app.HeadInt.Value = 24;
                    app.HeadSP.Value = 35;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 2;
                case "Hexer's Cover"
                    app.HeadInt.Value = 10;
                    app.HeadSP.Value = 41;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 0;
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: Finger2DropDown
        function Finger2DropDownValueChanged(app, event)
            value = app.Finger2DropDown.Value;
            
            switch value
                case "Doomcaller's Circlet"
                    app.HeadInt.Value = 26;
                    app.HeadSP.Value = 33;
                    app.HeadHit.Value = 1;
                    app.HeadCrit.Value = 1;
                case "Mish'undare"
                    app.HeadInt.Value = 24;
                    app.HeadSP.Value = 35;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 2;
                case "Hexer's Cover"
                    app.HeadInt.Value = 10;
                    app.HeadSP.Value = 41;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 0;
                case "Doomcaller's Circlet"
                    app.HeadInt.Value = 26;
                    app.HeadSP.Value = 33;
                    app.HeadHit.Value = 1;
                    app.HeadCrit.Value = 1;
                case "Mish'undare"
                    app.HeadInt.Value = 24;
                    app.HeadSP.Value = 35;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 2;
                case "Hexer's Cover"
                    app.HeadInt.Value = 10;
                    app.HeadSP.Value = 41;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 0;
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: WeaponDropDown
        function WeaponDropDownValueChanged(app, event)
            value = app.WeaponDropDown.Value;
            
            switch value
                case "Doomcaller's Circlet"
                    app.HeadInt.Value = 26;
                    app.HeadSP.Value = 33;
                    app.HeadHit.Value = 1;
                    app.HeadCrit.Value = 1;
                case "Mish'undare"
                    app.HeadInt.Value = 24;
                    app.HeadSP.Value = 35;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 2;
                case "Hexer's Cover"
                    app.HeadInt.Value = 10;
                    app.HeadSP.Value = 41;
                    app.HeadHit.Value = 0;
                    app.HeadCrit.Value = 0;
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: ShadowElixirCheckBox
        function ShadowElixirCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: GreaterArcaneElixirCheckBox
        function GreaterArcaneElixirCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: FlaskofSupremePowerCheckBox
        function FlaskofSupremePowerCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: DMTCheckBox
        function DMTCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: OnyCheckBox
        function OnyCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: HakkarCheckBox
        function HakkarCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: BrilliantWizardOilCheckBox
        function BrilliantWizardOilCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: Rank10ShadowboltCheckBox
        function Rank10ShadowboltCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end

        % Value changed function: MoonkinAuraCheckBox
        function MoonkinAuraCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                CalculateDPS(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GearAndDPS = uitab(app.TabGroup);
            app.GearAndDPS.Title = 'Gear and DPS';
            app.NeckInt = uieditfield(app.GearAndDPS, 'numeric');
            app.NeckInt.Editable = 'off';
            app.NeckInt.Value = 16;
            app.NeckSP = uieditfield(app.GearAndDPS, 'numeric');
            app.NeckSP.Editable = 'off';
            app.NeckHit = uieditfield(app.GearAndDPS, 'numeric');
            app.NeckHit.Editable = 'off';
            app.NeckHit.Value = 1;
            app.NeckCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.NeckCrit.Editable = 'off';
            app.ShouldersInt = uieditfield(app.GearAndDPS, 'numeric');
            app.ShouldersInt.Editable = 'off';
            app.ShouldersInt.Value = 11;
            app.ShouldersSP = uieditfield(app.GearAndDPS, 'numeric');
            app.ShouldersSP.Editable = 'off';
            app.ShouldersSP.Value = 28;
            app.ShouldersHit = uieditfield(app.GearAndDPS, 'numeric');
            app.ShouldersHit.Editable = 'off';
            app.ShouldersHit.Value = 1;
            app.ShouldersCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.ShouldersCrit.Editable = 'off';
            app.BackInt = uieditfield(app.GearAndDPS, 'numeric');
            app.BackInt.Editable = 'off';
            app.BackInt.Value = 10;
            app.BackSP = uieditfield(app.GearAndDPS, 'numeric');
            app.BackSP.Editable = 'off';
            app.BackSP.Value = 30;
            app.BackHit = uieditfield(app.GearAndDPS, 'numeric');
            app.BackHit.Editable = 'off';
            app.BackHit.Value = 1;
            app.BackCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.BackCrit.Editable = 'off';
            app.ChestInt = uieditfield(app.GearAndDPS, 'numeric');
            app.ChestInt.Editable = 'off';
            app.ChestInt.Value = 15;
            app.ChestSP = uieditfield(app.GearAndDPS, 'numeric');
            app.ChestSP.Editable = 'off';
            app.ChestSP.Value = 23;
            app.ChestHit = uieditfield(app.GearAndDPS, 'numeric');
            app.ChestHit.Editable = 'off';
            app.ChestCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.ChestCrit.Editable = 'off';
            app.ChestCrit.Value = 2;
            app.WristInt = uieditfield(app.GearAndDPS, 'numeric');
            app.WristInt.Editable = 'off';
            app.WristInt.Value = 13;
            app.WristSP = uieditfield(app.GearAndDPS, 'numeric');
            app.WristSP.Editable = 'off';
            app.WristSP.Value = 28;
            app.WristHit = uieditfield(app.GearAndDPS, 'numeric');
            app.WristHit.Editable = 'off';
            app.WristCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.WristCrit.Editable = 'off';
            app.Ring1Int = uieditfield(app.GearAndDPS, 'numeric');
            app.Ring1Int.Editable = 'off';
            app.Ring1Int.Value = 12;
            app.Ring1SP = uieditfield(app.GearAndDPS, 'numeric');
            app.Ring1SP.Editable = 'off';
            app.Ring1SP.Value = 21;
            app.Ring1Hit = uieditfield(app.GearAndDPS, 'numeric');
            app.Ring1Hit.Editable = 'off';
            app.Ring1Hit.Value = 1;
            app.Ring1Crit = uieditfield(app.GearAndDPS, 'numeric');
            app.Ring1Crit.Editable = 'off';
            app.Ring2Int = uieditfield(app.GearAndDPS, 'numeric');
            app.Ring2Int.Editable = 'off';
            app.Ring2SP = uieditfield(app.GearAndDPS, 'numeric');
            app.Ring2SP.Editable = 'off';
            app.Ring2SP.Value = 33;
            app.Ring2Hit = uieditfield(app.GearAndDPS, 'numeric');
            app.Ring2Hit.Editable = 'off';
            app.Ring2Crit = uieditfield(app.GearAndDPS, 'numeric');
            app.Ring2Crit.Editable = 'off';
            app.WaistInt = uieditfield(app.GearAndDPS, 'numeric');
            app.WaistInt.Editable = 'off';
            app.WaistInt.Value = 9;
            app.WaistSP = uieditfield(app.GearAndDPS, 'numeric');
            app.WaistSP.Editable = 'off';
            app.WaistSP.Value = 41;
            app.WaistHit = uieditfield(app.GearAndDPS, 'numeric');
            app.WaistHit.Editable = 'off';
            app.WaistCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.WaistCrit.Editable = 'off';
            app.WaistCrit.Value = 1;
            app.LegsInt = uieditfield(app.GearAndDPS, 'numeric');
            app.LegsInt.Editable = 'off';
            app.LegsInt.Value = 16;
            app.LegsSP = uieditfield(app.GearAndDPS, 'numeric');
            app.LegsSP.Editable = 'off';
            app.LegsSP.Value = 41;
            app.LegsHit = uieditfield(app.GearAndDPS, 'numeric');
            app.LegsHit.Editable = 'off';
            app.LegsCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.LegsCrit.Editable = 'off';
            app.LegsCrit.Value = 1;
            app.BootsInt = uieditfield(app.GearAndDPS, 'numeric');
            app.BootsInt.Editable = 'off';
            app.BootsInt.Value = 19;
            app.BootsSP = uieditfield(app.GearAndDPS, 'numeric');
            app.BootsSP.Editable = 'off';
            app.BootsSP.Value = 34;
            app.BootsHit = uieditfield(app.GearAndDPS, 'numeric');
            app.BootsHit.Editable = 'off';
            app.BootsCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.BootsCrit.Editable = 'off';
            app.Trinket1Int = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket1Int.Editable = 'off';
            app.Trinket1SP = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket1SP.Editable = 'off';
            app.Trinket1SP.Value = 11.73333;
            app.Trinket1Hit = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket1Hit.Editable = 'off';
            app.Trinket1Crit = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket1Crit.Editable = 'off';
            app.Trinket2Int = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket2Int.Editable = 'off';
            app.Trinket2SP = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket2SP.Editable = 'off';
            app.Trinket2SP.Value = 44;
            app.Trinket2Hit = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket2Hit.Editable = 'off';
            app.Trinket2Hit.Value = 2;
            app.Trinket2Crit = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket2Crit.Editable = 'off';
            app.WandInt = uieditfield(app.GearAndDPS, 'numeric');
            app.WandInt.Editable = 'off';
            app.WandSP = uieditfield(app.GearAndDPS, 'numeric');
            app.WandSP.Editable = 'off';
            app.WandSP.Value = 19;
            app.WandHit = uieditfield(app.GearAndDPS, 'numeric');
            app.WandHit.Editable = 'off';
            app.WandCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.WandCrit.Editable = 'off';
            app.WeaponInt = uieditfield(app.GearAndDPS, 'numeric');
            app.WeaponInt.Editable = 'off';
            app.WeaponInt.Value = 33;
            app.WeaponSP = uieditfield(app.GearAndDPS, 'numeric');
            app.WeaponSP.Editable = 'off';
            app.WeaponSP.Value = 76;
            app.WeaponHit = uieditfield(app.GearAndDPS, 'numeric');
            app.WeaponHit.Editable = 'off';
            app.WeaponHit.Value = 2;
            app.WeaponCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.WeaponCrit.Editable = 'off';
            app.WeaponCrit.Value = 1;
            app.OffhandInt = uieditfield(app.GearAndDPS, 'numeric');
            app.OffhandInt.Editable = 'off';
            app.OffhandSP = uieditfield(app.GearAndDPS, 'numeric');
            app.OffhandSP.Editable = 'off';
            app.OffhandHit = uieditfield(app.GearAndDPS, 'numeric');
            app.OffhandHit.Editable = 'off';
            app.OffhandCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.OffhandCrit.Editable = 'off';
            app.ReliableLabel = uilabel(app.GearAndDPS);
            app.ReliableLabel.Text = 'Reliable';
            app.AverageLabel = uilabel(app.GearAndDPS);
            app.AverageLabel.Text = 'Average';
            app.ProbabilityofDoingMoreLabel = uilabel(app.GearAndDPS);
            app.ProbabilityofDoingMoreLabel.Text = 'Probability of Doing More';
            app.TotalsLabel = uilabel(app.GearAndDPS);
            app.TotalsLabel.Text = 'Totals';
            app.ThantheEstimatedDPSLabel = uilabel(app.GearAndDPS);
            app.ThantheEstimatedDPSLabel.Text = 'Than the Estimated DPS';
            app.NumberofShadowBoltCastsEditFieldLabel = uilabel(app.GearAndDPS);
            app.NumberofShadowBoltCastsEditFieldLabel.Text = 'Shadow Bolt Casts';
            app.NumberofEditFieldLabel = uilabel(app.GearAndDPS);
            app.NumberofEditFieldLabel.Text = 'Number of ';
            app.NumberofLifeTapCasts = uieditfield(app.GearAndDPS, 'numeric');
            app.NumberofLifeTapCasts.Editable = 'off';
            app.NumberofShadowBoltCastsEditFieldLabel_2 = uilabel(app.GearAndDPS);
            app.NumberofShadowBoltCastsEditFieldLabel_2.Text = 'Life Tap Casts';
            app.HeadDropDown_2Label = uilabel(app.GearAndDPS);
            app.HeadDropDown_2Label.Text = 'Head';
            app.HeadDropDown = uidropdown(app.GearAndDPS);
            app.HeadDropDown.Items = {'Doomcaller''s Circlet', 'Mish''undare', 'Hexer''s Cover'};
            app.HeadDropDown.ValueChangedFcn = createCallbackFcn(app, @HeadDropDownValueChanged, true);
            app.HeadDropDown.Value = 'Doomcaller''s Circlet';
            app.NeckDropDown_2Label = uilabel(app.GearAndDPS);
            app.NeckDropDown_2Label.Text = 'Neck';
            app.NeckDropDown = uidropdown(app.GearAndDPS);
            app.NeckDropDown.Items = {'Soul Corrupter''s Necklace', 'Amulet of Vek''nilash', 'Choker of the Fire Lord'};
            app.NeckDropDown.ValueChangedFcn = createCallbackFcn(app, @NeckDropDownValueChanged, true);
            app.NeckDropDown.Value = 'Soul Corrupter''s Necklace';
            app.ShouldersDropDown_2Label = uilabel(app.GearAndDPS);
            app.ShouldersDropDown_2Label.Text = 'Shoulders';
            app.ShouldersDropDown = uidropdown(app.GearAndDPS);
            app.ShouldersDropDown.Items = {'Doomcaller''s Mantle', 'Mantle of the Blackwing Cabal'};
            app.ShouldersDropDown.ValueChangedFcn = createCallbackFcn(app, @ShouldersDropDownValueChanged, true);
            app.ShouldersDropDown.Value = 'Doomcaller''s Mantle';
            app.ChestDropDown_2Label = uilabel(app.GearAndDPS);
            app.ChestDropDown_2Label.Text = 'Chest';
            app.ChestDropDown = uidropdown(app.GearAndDPS);
            app.ChestDropDown.Items = {'Robe of Volatile Power', 'Garb of Royal Ascension', 'Doomcaller''s Robes'};
            app.ChestDropDown.ValueChangedFcn = createCallbackFcn(app, @ChestDropDownValueChanged, true);
            app.ChestDropDown.Value = 'Robe of Volatile Power';
            app.WristDropDown_2Label = uilabel(app.GearAndDPS);
            app.WristDropDown_2Label.Text = 'Wrist';
            app.WristDropDown = uidropdown(app.GearAndDPS);
            app.WristDropDown.Items = {'Burrower Bracers', 'Bracers of Arcane Accuracy'};
            app.WristDropDown.ValueChangedFcn = createCallbackFcn(app, @WristDropDownValueChanged, true);
            app.WristDropDown.Value = 'Burrower Bracers';
            app.HandsDropDown_2Label = uilabel(app.GearAndDPS);
            app.HandsDropDown_2Label.Text = 'Hands';
            app.HandsDropDown = uidropdown(app.GearAndDPS);
            app.HandsDropDown.Items = {'Dark Storm Gauntlets', 'Nemesis Gloves', 'Ebony Flame Gloves'};
            app.HandsDropDown.Value = 'Dark Storm Gauntlets';
            app.WaistDropDown_2Label = uilabel(app.GearAndDPS);
            app.WaistDropDown_2Label.Text = 'Waist';
            app.WaistDropDown = uidropdown(app.GearAndDPS);
            app.WaistDropDown.Items = {'Eyestalk Waistcord', 'Angelista''s Grasp'};
            app.WaistDropDown.Value = 'Eyestalk Waistcord';
            app.LegsDropDown_2Label = uilabel(app.GearAndDPS);
            app.LegsDropDown_2Label.Text = 'Legs';
            app.LegsDropDown = uidropdown(app.GearAndDPS);
            app.LegsDropDown.Items = {'Leggings of the Black Blizzard'};
            app.LegsDropDown.Value = 'Leggings of the Black Blizzard';
            app.FeetDropDownLabel = uilabel(app.GearAndDPS);
            app.FeetDropDownLabel.Text = 'Feet';
            app.FeetDropDown = uidropdown(app.GearAndDPS);
            app.FeetDropDown.Items = {'Boots of Epiphany'};
            app.FeetDropDown.Value = 'Boots of Epiphany';
            app.Finger1DropDownLabel = uilabel(app.GearAndDPS);
            app.Finger1DropDownLabel.Text = 'Finger 1';
            app.Finger1DropDown = uidropdown(app.GearAndDPS);
            app.Finger1DropDown.Items = {'Band of Forced Concentration'};
            app.Finger1DropDown.ValueChangedFcn = createCallbackFcn(app, @Finger1DropDownValueChanged, true);
            app.Finger1DropDown.Value = 'Band of Forced Concentration';
            app.Finger2DropDownLabel = uilabel(app.GearAndDPS);
            app.Finger2DropDownLabel.Text = 'Finger 2';
            app.Finger2DropDown = uidropdown(app.GearAndDPS);
            app.Finger2DropDown.Items = {'Ring of Spell Power'};
            app.Finger2DropDown.ValueChangedFcn = createCallbackFcn(app, @Finger2DropDownValueChanged, true);
            app.Finger2DropDown.Value = 'Ring of Spell Power';
            app.Trinket1DropDown_2Label = uilabel(app.GearAndDPS);
            app.Trinket1DropDown_2Label.Text = 'Trinket 1';
            app.Trinket1DropDown = uidropdown(app.GearAndDPS);
            app.Trinket1DropDown.Items = {'Talisman of Ephemeral Power'};
            app.Trinket1DropDown.Value = 'Talisman of Ephemeral Power';
            app.Trinket2DropDown_2Label = uilabel(app.GearAndDPS);
            app.Trinket2DropDown_2Label.Text = 'Trinket 2';
            app.Trinket2DropDown = uidropdown(app.GearAndDPS);
            app.Trinket2DropDown.Items = {'Neltharion''s Tear'};
            app.Trinket2DropDown.Value = 'Neltharion''s Tear';
            app.WeaponDropDown_2Label = uilabel(app.GearAndDPS);
            app.WeaponDropDown_2Label.Text = 'Weapon';
            app.WeaponDropDown = uidropdown(app.GearAndDPS);
            app.WeaponDropDown.Items = {'Qiraji Acolyte Staff'};
            app.WeaponDropDown.ValueChangedFcn = createCallbackFcn(app, @WeaponDropDownValueChanged, true);
            app.WeaponDropDown.Value = 'Qiraji Acolyte Staff';
            app.WandDropDown_2Label = uilabel(app.GearAndDPS);
            app.WandDropDown_2Label.Text = 'Wand';
            app.WandDropDown = uidropdown(app.GearAndDPS);
            app.WandDropDown.Items = {'Wand of Qiraji Nobility'};
            app.WandDropDown.Value = 'Wand of Qiraji Nobility';
            app.OffhandDropDown_2Label = uilabel(app.GearAndDPS);
            app.OffhandDropDown_2Label.Enable = 'off';
            app.OffhandDropDown_2Label.Text = 'Offhand';
            app.OffhandDropDown = uidropdown(app.GearAndDPS);
            app.OffhandDropDown.Items = {};
            app.OffhandDropDown.Enable = 'off';
            app.OffhandDropDown.Value = {};
            app.BackDropDown_2Label = uilabel(app.GearAndDPS);
            app.BackDropDown_2Label.Text = 'Back';
            app.BackDropDown = uidropdown(app.GearAndDPS);
            app.BackDropDown.Items = {'Cloak of the Devoured', 'Cloak of the Broodlord'};
            app.BackDropDown.ValueChangedFcn = createCallbackFcn(app, @BackDropDownValueChanged, true);
            app.BackDropDown.Value = 'Cloak of the Devoured';
            app.IntEditFieldLabel_4 = uilabel(app.GearAndDPS);
            app.IntEditFieldLabel_4.Text = 'Int';
            app.HeadInt = uieditfield(app.GearAndDPS, 'numeric');
            app.HeadInt.Editable = 'off';
            app.HeadInt.Value = 26;
            app.SPEditFieldLabel_4 = uilabel(app.GearAndDPS);
            app.SPEditFieldLabel_4.Text = 'SP';
            app.HeadSP = uieditfield(app.GearAndDPS, 'numeric');
            app.HeadSP.Editable = 'off';
            app.HeadSP.Value = 33;
            app.HitLabel_4 = uilabel(app.GearAndDPS);
            app.HitLabel_4.Text = 'Hit (%)';
            app.HeadHit = uieditfield(app.GearAndDPS, 'numeric');
            app.HeadHit.Editable = 'off';
            app.HeadHit.Value = 1;
            app.CritLabel_4 = uilabel(app.GearAndDPS);
            app.CritLabel_4.Text = 'Crit (%)';
            app.HeadCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.HeadCrit.Editable = 'off';
            app.HeadCrit.Value = 1;
            app.FightLengthsecondsEditField_2Label = uilabel(app.GearAndDPS);
            app.FightLengthsecondsEditField_2Label.Text = 'Fight Length (seconds)';
            app.FightLengthsecondsEditField = uieditfield(app.GearAndDPS, 'numeric');
            app.FightLengthsecondsEditField.Value = 120;
            app.IntEditFieldLabel_5 = uilabel(app.GearAndDPS);
            app.IntEditFieldLabel_5.Text = 'Int';
            app.HandsInt = uieditfield(app.GearAndDPS, 'numeric');
            app.HandsInt.Editable = 'off';
            app.HandsInt.Value = 26;
            app.SPEditFieldLabel_5 = uilabel(app.GearAndDPS);
            app.SPEditFieldLabel_5.Text = 'SP';
            app.HandsSP = uieditfield(app.GearAndDPS, 'numeric');
            app.HandsSP.Editable = 'off';
            app.HandsSP.Value = 33;
            app.HitLabel_5 = uilabel(app.GearAndDPS);
            app.HitLabel_5.Text = 'Hit (%)';
            app.HandsHit = uieditfield(app.GearAndDPS, 'numeric');
            app.HandsHit.Editable = 'off';
            app.HandsHit.Value = 1;
            app.CritLabel_5 = uilabel(app.GearAndDPS);
            app.CritLabel_5.Text = 'Crit (%)';
            app.HandsCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.HandsCrit.Editable = 'off';
            app.HandsCrit.Value = 1;
            app.ExtremeLabel_3 = uilabel(app.GearAndDPS);
            app.ExtremeLabel_3.Text = 'Extreme';
            app.PreferenceSlider = uislider(app.GearAndDPS);
            app.PreferenceSlider.Limits = [0 1];
            app.PreferenceSlider.Value = 0.5;
            app.IntEditFieldLabel_6 = uilabel(app.GearAndDPS);
            app.IntEditFieldLabel_6.Text = 'Int';
            app.TotalInt = uieditfield(app.GearAndDPS, 'numeric');
            app.TotalInt.Editable = 'off';
            app.TotalInt.Value = 206;
            app.SPEditFieldLabel_6 = uilabel(app.GearAndDPS);
            app.SPEditFieldLabel_6.Text = 'SP';
            app.TotalSP = uieditfield(app.GearAndDPS, 'numeric');
            app.TotalSP.Editable = 'off';
            app.TotalSP.Value = 495.7;
            app.HitLabel_6 = uilabel(app.GearAndDPS);
            app.HitLabel_6.Text = 'Hit (%)';
            app.TotalHit = uieditfield(app.GearAndDPS, 'numeric');
            app.TotalHit.Editable = 'off';
            app.TotalHit.Value = 10;
            app.CritLabel_6 = uilabel(app.GearAndDPS);
            app.CritLabel_6.Text = 'Crit (%)';
            app.TotalCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.TotalCrit.Editable = 'off';
            app.TotalCrit.Value = 7;
            app.ManaLabel_2 = uilabel(app.GearAndDPS);
            app.ManaLabel_2.Text = 'Mana';
            app.TotalMana = uieditfield(app.GearAndDPS, 'numeric');
            app.TotalMana.Editable = 'off';
            app.TotalMana.Value = 5803;
            app.NumberofEditFieldLabel_4 = uilabel(app.GearAndDPS);
            app.NumberofEditFieldLabel_4.Text = 'Number of ';
            app.NumberofShadowBoltCasts = uieditfield(app.GearAndDPS, 'numeric');
            app.NumberofShadowBoltCasts.Editable = 'off';
            app.CalculateButton = uibutton(app.GearAndDPS, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.EstimatedDPSEditFieldLabel_2 = uilabel(app.GearAndDPS);
            app.EstimatedDPSEditFieldLabel_2.Text = 'Estimated DPS';
            app.EstimatedDPSEditField = uieditfield(app.GearAndDPS, 'numeric');
            app.EstimatedDPSEditField.Editable = 'off';
            app.SaveDPSCheckBox = uicheckbox(app.GearAndDPS);
            app.SaveDPSCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveDPSCheckBoxValueChanged, true);
            app.SaveDPSCheckBox.Text = ' Save DPS';
            app.SavedDPSEditField = uieditfield(app.GearAndDPS, 'numeric');
            app.SavedDPSEditField.Editable = 'off';
            app.SavedDifference = uieditfield(app.GearAndDPS, 'numeric');
            app.SavedDifference.Editable = 'off';
            app.DMTCheckBox = uicheckbox(app.GearAndDPS);
            app.DMTCheckBox.ValueChangedFcn = createCallbackFcn(app, @DMTCheckBoxValueChanged, true);
            app.DMTCheckBox.Text = 'DMT';
            app.OnyCheckBox = uicheckbox(app.GearAndDPS);
            app.OnyCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnyCheckBoxValueChanged, true);
            app.OnyCheckBox.Text = 'Ony';
            app.HakkarCheckBox = uicheckbox(app.GearAndDPS);
            app.HakkarCheckBox.ValueChangedFcn = createCallbackFcn(app, @HakkarCheckBoxValueChanged, true);
            app.HakkarCheckBox.Text = 'Hakkar';
            app.ShadowElixirCheckBox = uicheckbox(app.GearAndDPS);
            app.ShadowElixirCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShadowElixirCheckBoxValueChanged, true);
            app.ShadowElixirCheckBox.Text = 'Shadow Elixir';
            app.GreaterArcaneElixirCheckBox = uicheckbox(app.GearAndDPS);
            app.GreaterArcaneElixirCheckBox.ValueChangedFcn = createCallbackFcn(app, @GreaterArcaneElixirCheckBoxValueChanged, true);
            app.GreaterArcaneElixirCheckBox.Text = 'Greater Arcane Elixir';
            app.FlaskofSupremePowerCheckBox = uicheckbox(app.GearAndDPS);
            app.FlaskofSupremePowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlaskofSupremePowerCheckBoxValueChanged, true);
            app.FlaskofSupremePowerCheckBox.Text = 'Flask of Supreme Power';
            app.BrilliantWizardOilCheckBox = uicheckbox(app.GearAndDPS);
            app.BrilliantWizardOilCheckBox.ValueChangedFcn = createCallbackFcn(app, @BrilliantWizardOilCheckBoxValueChanged, true);
            app.BrilliantWizardOilCheckBox.Text = 'Brilliant Wizard Oil';
            app.Rank10ShadowboltCheckBox = uicheckbox(app.GearAndDPS);
            app.Rank10ShadowboltCheckBox.ValueChangedFcn = createCallbackFcn(app, @Rank10ShadowboltCheckBoxValueChanged, true);
            app.Rank10ShadowboltCheckBox.Text = 'Rank 10 Shadowbolt';
            app.MoonkinAuraCheckBox = uicheckbox(app.GearAndDPS);
            app.MoonkinAuraCheckBox.ValueChangedFcn = createCallbackFcn(app, @MoonkinAuraCheckBoxValueChanged, true);
            app.MoonkinAuraCheckBox.Text = 'Moonkin Aura';
            app.SongflowerCheckBox = uicheckbox(app.GearAndDPS);
            app.SongflowerCheckBox.Text = 'Songflower';
            app.SavedRelativeDifference = uieditfield(app.GearAndDPS, 'numeric');
            app.SavedRelativeDifference.Editable = 'off';
            app.OptimizeButton = uibutton(app.GearAndDPS, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.TalentsTab = uitab(app.TabGroup);
            app.TalentsTab.Title = 'Talents';
            app.TalentModificationsComingSoonLabel = uilabel(app.TalentsTab);
            app.TalentModificationsComingSoonLabel.Text = 'Talent Modifications Coming Soon!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WarlockGearApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end